classdef Expediente_Clinico1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DatosdelPacientePanel          matlab.ui.container.Panel
        IMCEditField                   matlab.ui.control.NumericEditField
        IMCEditFieldLabel              matlab.ui.control.Label
        TipodesangreEditField_2        matlab.ui.control.EditField
        TipodesangreEditField_2Label   matlab.ui.control.Label
        SexoEditField                  matlab.ui.control.EditField
        SexoEditFieldLabel             matlab.ui.control.Label
        HabitusExteriorTextArea        matlab.ui.control.TextArea
        HabitusExteriorTextAreaLabel   matlab.ui.control.Label
        DomicilioTextArea              matlab.ui.control.TextArea
        DomicilioTextAreaLabel         matlab.ui.control.Label
        NuevoPacienteButton            matlab.ui.control.Button
        EdadEditField_2                matlab.ui.control.NumericEditField
        EdadEditField_2Label           matlab.ui.control.Label
        ApellidosEditField_2           matlab.ui.control.EditField
        ApellidosEditField_2Label      matlab.ui.control.Label
        NombresEditField_2             matlab.ui.control.EditField
        NombresEditField_2Label        matlab.ui.control.Label
        ConfirmacionPanel              matlab.ui.container.Panel
        Lampconfirmacion               matlab.ui.control.Lamp
        SoncorrectortusdatosSwitch     matlab.ui.control.Switch
        ValidardatosLabel              matlab.ui.control.Label
        EstudiosdelaboratorioygabinetePanel  matlab.ui.container.Panel
        Dato10EditField                matlab.ui.control.NumericEditField
        Dato10EditFieldLabel           matlab.ui.control.Label
        Dato9EditField                 matlab.ui.control.NumericEditField
        Dato9EditFieldLabel            matlab.ui.control.Label
        Dato8EditField                 matlab.ui.control.NumericEditField
        Dato8EditFieldLabel            matlab.ui.control.Label
        Dato7EditField                 matlab.ui.control.NumericEditField
        Dato7EditFieldLabel            matlab.ui.control.Label
        Dato6EditField                 matlab.ui.control.NumericEditField
        Dato6EditFieldLabel            matlab.ui.control.Label
        Dato5EditField                 matlab.ui.control.NumericEditField
        Dato5EditFieldLabel            matlab.ui.control.Label
        Dato4EditField                 matlab.ui.control.NumericEditField
        Dato4EditFieldLabel            matlab.ui.control.Label
        Dato3EditField                 matlab.ui.control.NumericEditField
        Dato3EditFieldLabel            matlab.ui.control.Label
        Dato2EditField                 matlab.ui.control.NumericEditField
        Dato2EditFieldLabel            matlab.ui.control.Label
        Dato1EditField                 matlab.ui.control.NumericEditField
        Dato1EditFieldLabel            matlab.ui.control.Label
        SaturacindeoxgenoenlasangreSO2Label  matlab.ui.control.Label
        SATURACIONDEOXIGENOPanel       matlab.ui.container.Panel
        SaturacionOxigeno              matlab.ui.control.UIAxes
        ELECTROCARDIOGRAMAPanel        matlab.ui.container.Panel
        ECG                            matlab.ui.control.UIAxes
        HistoriaclinicaPanel           matlab.ui.container.Panel
        TratamientospreviosTextArea    matlab.ui.control.TextArea
        TratamientospreviosTextAreaLabel  matlab.ui.control.Label
        PadecimientoactualTextArea     matlab.ui.control.TextArea
        PadecimientoactualTextAreaLabel  matlab.ui.control.Label
        OtroEditField_2                matlab.ui.control.EditField
        OtroEditField_2Label           matlab.ui.control.Label
        EnferemedadcronicaEditField    matlab.ui.control.EditField
        EnferemedadcronicaEditFieldLabel  matlab.ui.control.Label
        CirugasEditField               matlab.ui.control.EditField
        CirugasEditFieldLabel          matlab.ui.control.Label
        TransfusionesEditField         matlab.ui.control.EditField
        TransfusionesEditFieldLabel    matlab.ui.control.Label
        FracturasEditField             matlab.ui.control.EditField
        FracturasEditFieldLabel        matlab.ui.control.Label
        TipodesangreEditField          matlab.ui.control.EditField
        TipodesangreEditFieldLabel     matlab.ui.control.Label
        PsicotropicoEditField          matlab.ui.control.EditField
        PsicotropicoEditFieldLabel     matlab.ui.control.Label
        TabaquismoEditField            matlab.ui.control.EditField
        TabaquismoEditFieldLabel       matlab.ui.control.Label
        OtroEditField                  matlab.ui.control.EditField
        OtroEditFieldLabel             matlab.ui.control.Label
        ANTECEDENTESPERSONALESNOPATOLOGICOSLabel  matlab.ui.control.Label
        ANTECEDENTESPERSONALESPATOLOGICOSLabel  matlab.ui.control.Label
        NeuropatiaEditField            matlab.ui.control.EditField
        NeuropatiaEditFieldLabel       matlab.ui.control.Label
        TranstornospsicologicosEditField  matlab.ui.control.EditField
        TranstornospsicologicosEditFieldLabel  matlab.ui.control.Label
        CancerEditField                matlab.ui.control.EditField
        CancerEditFieldLabel           matlab.ui.control.Label
        HipertensinArterialEditField   matlab.ui.control.EditField
        HipertensinArterialEditFieldLabel  matlab.ui.control.Label
        ANTECEDENTESHEREDOFAMILIARESLabel  matlab.ui.control.Label
        AlcoholEditField               matlab.ui.control.EditField
        AlcoholEditFieldLabel          matlab.ui.control.Label
        AlergasEditField               matlab.ui.control.EditField
        AlergasEditFieldLabel          matlab.ui.control.Label
        DiabetesEditField              matlab.ui.control.EditField
        AntecendentesheredofamiliaresLabel  matlab.ui.control.Label
        GrupoetnicoEditField           matlab.ui.control.EditField
        GrupoetnicoEditFieldLabel      matlab.ui.control.Label
        FolioEditField                 matlab.ui.control.NumericEditField
        FolioEditFieldLabel            matlab.ui.control.Label
        ExploracinFisicaPanel          matlab.ui.container.Panel
        TallamEditField                matlab.ui.control.NumericEditField
        TallamEditFieldLabel           matlab.ui.control.Label
        PesoKgEditField                matlab.ui.control.NumericEditField
        PesoKgEditFieldLabel           matlab.ui.control.Label
        FrecuenciarespiratoriarespminEditField  matlab.ui.control.NumericEditField
        FrecuenciarespiratoriarespminEditFieldLabel  matlab.ui.control.Label
        FrecuenciacardiacalatminEditField  matlab.ui.control.NumericEditField
        FrecuenciacardiacalatminEditFieldLabel  matlab.ui.control.Label
        TensinArterialmmHgEditField    matlab.ui.control.EditField
        TensinArterialmmHgEditFieldLabel  matlab.ui.control.Label
        TemperaturaCEditField          matlab.ui.control.NumericEditField
        TemperaturaCEditFieldLabel     matlab.ui.control.Label
        SignosvitalesLabel             matlab.ui.control.Label
        HabitusexteriorEditField       matlab.ui.control.EditField
        HabitusexteriorEditFieldLabel  matlab.ui.control.Label
        DatosgeneralesPanel            matlab.ui.container.Panel
        SexoMoFEditField               matlab.ui.control.EditField
        SexoMoFEditFieldLabel          matlab.ui.control.Label
        Datos                          matlab.ui.control.Label
        ContraseaEditField             matlab.ui.control.EditField
        ContraseaEditFieldLabel        matlab.ui.control.Label
        CargarDatosButton              matlab.ui.control.Button
        LampPassword                   matlab.ui.control.Lamp
        Validar                        matlab.ui.control.Label
        AccederButton                  matlab.ui.control.Button
        DomicilioPanel                 matlab.ui.container.Panel
        NumeroexteriorEditField        matlab.ui.control.EditField
        NumeroexteriorEditFieldLabel   matlab.ui.control.Label
        NumerointeriorEditField        matlab.ui.control.EditField
        NumerointeriorEditFieldLabel   matlab.ui.control.Label
        CalleEditField                 matlab.ui.control.EditField
        CalleEditFieldLabel            matlab.ui.control.Label
        ColoniaEditField               matlab.ui.control.EditField
        ColoniaEditFieldLabel          matlab.ui.control.Label
        CodigopostalEditField          matlab.ui.control.NumericEditField
        CodigopostalEditFieldLabel     matlab.ui.control.Label
        MunicipiooalcaldiaEditField    matlab.ui.control.EditField
        MunicipiooalcaldiaEditFieldLabel  matlab.ui.control.Label
        EntidadfederativaEditField     matlab.ui.control.EditField
        EntidadfederativaEditFieldLabel  matlab.ui.control.Label
        EdadEditField                  matlab.ui.control.NumericEditField
        EdadEditFieldLabel             matlab.ui.control.Label
        ApellidosEditField             matlab.ui.control.EditField
        ApellidosEditFieldLabel        matlab.ui.control.Label
        NombresEditField               matlab.ui.control.EditField
        NombresEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Folio % Folio
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CargarDatosButton.Enable = 'off';
            app.SoncorrectortusdatosSwitch.Enable = 'off';
            app.FolioEditField.Editable = 'off'
            app.FolioEditField.FontWeight = 'bold';
            app.Folio = 0;
            app.ECG.Visible = 'off';
            
        end

        % Button pushed function: AccederButton
        function AccederButtonPushed(app, event)
            PW = '1234';
            Password = app.ContraseaEditField.Value;
            if  strcmp(Password,PW)
                app.LampPassword.Color = 'g';
                app.Validar.Text = 'Correcto';
                app.CargarDatosButton.Enable = 'on';
                app.ContraseaEditField.Value = '****';
                app.ContraseaEditField.Enable = 'off';
                app.AccederButton.Enable = 'off';
                app.Folio = app.Folio + 1; % Contador del folio
                app.FolioEditField.Enable = 'on';
                app.FolioEditField.Visible = 'on';
                app.FolioEditFieldLabel.Visible = 'on'
                app.FolioEditField.Value = app.Folio;
                %Habilitamos todo
                app.DatosdelPacientePanel.Enable = 'on';
                app.DatosgeneralesPanel.Enable = 'on';
                app.EstudiosdelaboratorioygabinetePanel.Enable = 'on';
                app.HistoriaclinicaPanel.Enable = 'on';
                app.ExploracinFisicaPanel.Enable = 'on';
                app.DomicilioPanel.Enable = 'on';
                app.ConfirmacionPanel.Enable = 'on';
                app.NombresEditField.Enable = 'on';
                app.EdadEditField.Enable = 'on';
                app.ApellidosEditField.Enable = 'on';
                app.SexoMoFEditField.Enable = 'on';

            else
                app.LampPassword.Color = 'r';
                app.Validar.Text = 'Incorrecto';
            end
            
        end

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            %Verificar casillas vacias
            %Datos generales
            a = strcmp(app.NombresEditField.Value,'');
            b = strcmp(app.ApellidosEditField.Value,'');
            c = strcmp(num2str(app.EdadEditField.Value),'0'); %Comparacion 0 en ascii
            cc = strcmp(app.SexoMoFEditField.Value,'');
            %Direccion
            d = strcmp(app.NumeroexteriorEditField.Value,'');
            e = strcmp(app.NumerointeriorEditField.Value,'');
            f = strcmp(app.CalleEditField.Value,'');
            g = strcmp(app.ColoniaEditField.Value,'');
            h = strcmp(num2str(app.CodigopostalEditField.Value),'0');
            i = strcmp(app.MunicipiooalcaldiaEditField.Value,'');
            j = strcmp(app.EntidadfederativaEditField.Value,'');
            %Historia Clínica
            ha = strcmp(app.AlcoholEditField.Value,'');
            hb = strcmp(app.TratamientospreviosTextArea.Value,'');
            hc = strcmp(app.PadecimientoactualTextArea.Value,'');
            hd = strcmp(app.OtroEditField_2.Value,'');
            he = strcmp(app.EnferemedadcronicaEditField.Value,'');
            hf = strcmp(app.CirugasEditField.Value,'');
            hg = strcmp(app.TransfusionesEditField.Value,'');
            hh = strcmp(app.FracturasEditField.Value,'');
            hi = strcmp(app.TipodesangreEditField.Value,'');
            hj = strcmp(app.PsicotropicoEditField.Value,'');
            hk = strcmp(app.TabaquismoEditField.Value,'');
            hl = strcmp(app.OtroEditField.Value,'');
            hm = strcmp(app.NeuropatiaEditField.Value,'');
            hn = strcmp(app.TranstornospsicologicosEditField.Value,'');
            ho = strcmp(app.CancerEditField.Value,'');
            hp = strcmp(app.HipertensinArterialEditField.Value,'');
            hq = strcmp(app.AlcoholEditField.Value,'');
            hr = strcmp(app.AlergasEditField.Value,'');
            hs = strcmp(app.DiabetesEditField.Value,'');
            ht = strcmp(app.GrupoetnicoEditField.Value,'');
            
            %Exploracion Fisica
            r = strcmp(app.HabitusexteriorEditField.Value,'');
            s = strcmp(num2str(app.TallamEditField.Value),'0');
            t = strcmp(app.TensinArterialmmHgEditField.Value,'');
            w = strcmp(num2str(app.PesoKgEditField.Value),'0');
            x = strcmp(num2str(app.FrecuenciarespiratoriarespminEditField.Value),'0');
            y = strcmp(num2str(app.FrecuenciacardiacalatminEditField.Value),'0');
            z = strcmp(num2str(app.TemperaturaCEditField.Value),'0');

            %%Datos grafica saturación
            s1 = strcmp(num2str(app.Dato1EditField.Value),'0');  
            s2 = strcmp(num2str(app.Dato2EditField.Value),'0'); 
            s3 = strcmp(num2str(app.Dato3EditField.Value),'0'); 
            s4 = strcmp(num2str(app.Dato4EditField.Value),'0'); 
            s5 = strcmp(num2str(app.Dato5EditField.Value),'0'); 
            s6 = strcmp(num2str(app.Dato6EditField.Value),'0'); 
            s7 = strcmp(num2str(app.Dato7EditField.Value),'0'); 
            s8 = strcmp(num2str(app.Dato8EditField.Value),'0'); 
            s9 = strcmp(num2str(app.Dato9EditField.Value),'0'); 
            s10 = strcmp(num2str(app.Dato10EditField.Value),'0'); 

            %Datos completos
            if (a == 0 && b == 0 && c == 0 && cc == 0 && d == 0 && e == 0 && f == 0 && g == 0 && h == 0 && i == 0 && j == 0 && ha == 0 && hb == 0 && hc == 0 && hd == 0 && he == 0 && hf == 0 && hg == 0 && hh == 0 && hi == 0 && hj == 0 && hk == 0 && hl == 0 && hm == 0 && hn == 0 && ho == 0 && hp == 0 && hq == 0 && hr == 0 && hs == 0 && ht == 0 && r == 0 && s == 0 && t == 0 && w == 0 && x == 0 && y == 0 && z == 0 && s1 == 0 && s2 == 0 && s3 == 0 && s4 == 0 && s5 == 0 && s6 == 0 && s7 == 0 && s8 == 0 && s9 == 0 && s10 == 0) %Datos vacios
            %if a == 0
                app.Datos.Text = 'Datos completos';
                app.SoncorrectortusdatosSwitch.Enable = 'on';

                %%ECG 
                app.ECG.Visible = 'on';

                ECG = [109,109,109,109,109,109,110,110,110,110,111,112,114,116,120,123,128,132,136,140,145,149,153,158,162,165,167,168,167,164];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
                ECG = ECG * 1.5e-3 / 255;
                fs = 200;
                ts = 1/fs;
                tf = 200*ts % tiempo final = Datos totales * ts
                t = 0:ts:tf-ts;
            
                plot(app.ECG,t,ECG,'LineWidth',2,'Color',[1,0.1,0.2])
                axis(app.ECG,[0,tf,0,1.8e-3])
                
                
                %%Saturacion de oxigeno
                app.SaturacionOxigeno.Visible = 'on';

                sat1 = app.Dato1EditField.Value;
                sat2 = app.Dato2EditField.Value;
                sat3 = app.Dato3EditField.Value;
                sat4 = app.Dato4EditField.Value;
                sat5 = app.Dato5EditField.Value;
                sat6 = app.Dato6EditField.Value;
                sat7 = app.Dato7EditField.Value;
                sat8 = app.Dato8EditField.Value;
                sat9 = app.Dato9EditField.Value;
                sat10 = app.Dato10EditField.Value;

                Vsat = [sat1, sat2, sat3, sat4, sat5, sat6, sat7, sat8, sat9,sat10];
                    
                Presion = 10:10:100;

                plot(app.SaturacionOxigeno,Presion,Vsat,'LineWidth',2,'Color',[0.1,0.2,1])
                axis(app.SaturacionOxigeno,[0,100,0,100])

                %%IMC
                P = app.PesoKgEditField.Value;
                A = app.TallamEditField.Value;
                IMC = P/A^2;
                app.IMCEditField.Value = IMC;
                % 
                % if IMC < 18.5
                %     app.INDICADORLamp.Color = 'k';
                % elseif IMC >= 18.5 && IMC <= 24.9
                %     app.INDICADORLamp.Color = 'g';
                % elseif IMC > 24.9 && IMC <= 30
                %     app.INDICADORLamp.Color = 'o'
                % else
                %     app.INDICADORLamp.Color = 'r'
                % end

            else %Datos Incompletos
                app.Datos.Text = 'Porfavor completa todos los campos';
            end
        end

        % Value changed function: SoncorrectortusdatosSwitch
        function SoncorrectortusdatosSwitchValueChanged(app, event)
            confirmacion = app.SoncorrectortusdatosSwitch.Value;
            if strcmp(confirmacion,'On')
                app.CargarDatosButton.Enable = 'off';
                app.SoncorrectortusdatosSwitch.Enable = 'off';
                app.Lampconfirmacion.Color = 'g';
                
                %Guardar datos finales
                nombre = app.NombresEditField.Value;
                apellido = app.ApellidosEditField.Value;
                edad = app.EdadEditField.Value;
                habitus = app.HabitusexteriorEditField.Value;
                sangre = app.TipodesangreEditField.Value;
                sexo = app.SexoMoFEditField.Value;
                calle = app.CalleEditField.Value;
                colonia = app.ColoniaEditField.Value;
                numext = app.NumeroexteriorEditField.Value;
                ciudad = app.EntidadfederativaEditField.Value;
                
                %Se confirmo bloqueamos campos
                app.NombresEditField.Enable = 'off';
                app.ApellidosEditField.Enable = 'off';
                app.EdadEditField.Enable = 'off';
                app.SexoMoFEditField.Enable = 'off';

                app.TratamientospreviosTextArea.Enable = 'off';
                app.PadecimientoactualTextArea.Enable = 'off';
                app.OtroEditField_2.Enable = 'off';
                app.EnferemedadcronicaEditField.Enable = 'off';
                app.CirugasEditField.Enable = 'off';
                app.TransfusionesEditField.Enable = 'off';
                app.FracturasEditField.Enable = 'off';
                app.TipodesangreEditField.Enable = 'off';
                app.PsicotropicoEditField.Enable = 'off';
                app.TabaquismoEditField.Enable = 'off';
                app.OtroEditField.Enable = 'off';
                app.NeuropatiaEditField.Enable = 'off';
                app.TranstornospsicologicosEditField.Enable = 'off';
                app.CancerEditField.Enable = 'off';
                app.HipertensinArterialEditField.Enable = 'off';
                app.AlcoholEditField.Enable = 'off';
                app.AlergasEditField.Enable = 'off';
                app.DiabetesEditField.Enable = 'off';
                app.GrupoetnicoEditField.Enable = 'off';
                app.HabitusexteriorEditField.Enable = 'off';

                app.TallamEditField.Enable = 'off';
                app.PesoKgEditField.Enable = 'off';
                app.FrecuenciarespiratoriarespminEditField.Enable = 'off';
                app.FrecuenciacardiacalatminEditField.Enable = 'off';
                app.TensinArterialmmHgEditField.Enable = 'off';
                app.TemperaturaCEditField.Enable = 'off';

                app.NumeroexteriorEditField.Enable = 'off';
                app.NumerointeriorEditField.Enable = 'off';
                app.CalleEditField.Enable = 'off';
                app.ColoniaEditField.Enable = 'off';
                app.CodigopostalEditField.Enable = 'off';
                app.MunicipiooalcaldiaEditField.Enable = 'off';
                app.EntidadfederativaEditField.Enable = 'off';
                app.Dato1EditField.Enable = 'off';
                app.Dato2EditField.Enable = 'off';
                app.Dato3EditField.Enable = 'off';
                app.Dato4EditField.Enable = 'off';
                app.Dato5EditField.Enable = 'off';
                app.Dato6EditField.Enable = 'off';
                app.Dato7EditField.Enable = 'off';
                app.Dato8EditField.Enable = 'off';
                app.Dato9EditField.Enable = 'off';
                app.Dato10EditField.Enable = 'off';
               
                % Mostrar datos finales supongo que solo algunos
                app.IMCEditField.Visible = 'on';
                app.IMCEditFieldLabel.Visible = 'on';

                app.NombresEditField_2.Value = nombre;
                app.NombresEditField_2.Editable = 'off';
                app.NombresEditField_2.Visible = 'on';
                app.NombresEditField_2Label.Visible = 'on';
                app.NombresEditField_2.FontWeight = 'bold';
                app.ApellidosEditField_2.Value = apellido;
                app.ApellidosEditField_2.Editable = 'off';
                app.ApellidosEditField_2.Visible = 'on';
                app.ApellidosEditField_2Label.Visible = 'on';
                app.ApellidosEditField_2.FontWeight = 'bold';
                app.EdadEditField_2.Value = edad;
                app.EdadEditField_2.Editable = 'off';
                app.EdadEditField_2.Visible = 'on';
                app.EdadEditField_2Label.Visible = 'on';
                app.EdadEditField_2.FontWeight = 'bold';
                app.SexoEditField.Value = sexo;
                app.SexoEditField.Editable = 'off';
                app.SexoEditField.Visible = 'on';
                app.SexoEditFieldLabel.Visible = 'on';
                app.SexoEditField.FontWeight = 'bold';
                
                app.TipodesangreEditField_2.Value = sangre;
                app.TipodesangreEditField_2.Editable = 'off';
                app.TipodesangreEditField_2.Visible = 'on';
                app.TipodesangreEditField_2Label.Visible = 'on';
                app.TipodesangreEditField_2.FontWeight = 'bold';

                app.HabitusExteriorTextArea.Value = habitus;
                app.HabitusExteriorTextArea.Editable = 'off';
                app.HabitusExteriorTextArea.Visible = 'on';
                app.HabitusExteriorTextAreaLabel.Visible = 'on';
                app.HabitusExteriorTextArea.FontWeight = 'bold';

                app.DomicilioTextArea.Value = [calle,' ',colonia,' #',numext, ' ',ciudad];
                app.DomicilioTextArea.Editable = 'off';
                app.DomicilioTextArea.Visible = 'on';
                app.DomicilioTextAreaLabel.Visible = 'on';
                app.DomicilioTextArea.FontWeight = 'bold';


                %Regresar al inicio
                app.SoncorrectortusdatosSwitch.Value = 'Off';
                app.NuevoPacienteButton.Enable = 'on';
            end
        end

        % Button pushed function: NuevoPacienteButton
        function NuevoPacienteButtonPushed(app, event)
            app.NuevoPacienteButton.Enable = 'off';
            
            app.FolioEditField.Visible = 'off';
            app.FolioEditFieldLabel.Visible = 'off';
            app.LampPassword.Color = 'r';

            %%Borrar todos los campos  y regresarlos a activados
            app.NombresEditField.Enable = 'on';
            app.NombresEditField.Value = '';
            app.ApellidosEditField.Enable = 'on';
            app.ApellidosEditField.Value = '';
            app.EdadEditField.Enable = 'on';
            app.EdadEditField.Value = 0;
            app.SexoMoFEditField.Enable = 'on';
            app.SexoMoFEditField.Value = '';

            app.HabitusexteriorEditField.Enable = 'on';
            app.HabitusexteriorEditField.Value = '';
            app.AlcoholEditField.Enable = 'on';
            app.AlcoholEditField.Value = '';
            app.TratamientospreviosTextArea.Enable = 'on';
            app.TratamientospreviosTextArea.Value = '';
            app.OtroEditField_2.Enable = 'on';
            app.OtroEditField_2.Value = '';
            app.EnferemedadcronicaEditField.Enable = 'on';
            app.EnferemedadcronicaEditField.Value = '';
            app.CirugasEditField.Enable = 'on';
            app.CirugasEditField.Value = '';
            app.TransfusionesEditField.Enable = 'on';
            app.TransfusionesEditField.Value = '';
            app.FracturasEditField.Enable = 'on';
            app.FracturasEditField.Value = '';
            app.TipodesangreEditField.Enable = 'on';
            app.TipodesangreEditField.Value = '';
            app.PsicotropicoEditField.Enable = 'on';
            app.PsicotropicoEditField.Value = '';
            app.TabaquismoEditField.Enable = 'on';
            app.TabaquismoEditField.Value = '';
            app.OtroEditField.Enable = 'on';
            app.OtroEditField.Value = '';
            app.NeuropatiaEditField.Enable = 'on';
            app.NeuropatiaEditField.Value = '';
            app.TranstornospsicologicosEditField.Enable = 'on';
            app.TranstornospsicologicosEditField.Value = '';
            app.CancerEditField.Enable = 'on';
            app.CancerEditField.Value = '';
            app.HipertensinArterialEditField.Enable = 'on';
            app.HipertensinArterialEditField.Value = '';
            app.PadecimientoactualTextArea.Enable = 'on';
            app.PadecimientoactualTextArea.Value = '';
            app.AlergasEditField.Enable = 'on';
            app.AlergasEditField.Value = '';
            app.DiabetesEditField.Enable = 'on';
            app.DiabetesEditField.Value = '';
            app.GrupoetnicoEditField.Enable = 'on';
            app.GrupoetnicoEditField.Value = '';
            
            %%Reiniciar datos saturacion
            app.Dato1EditField.Enable = 'on';
            app.Dato1EditField.Value = 0;
            app.Dato2EditField.Enable = 'on';
            app.Dato2EditField.Value = 0;
            app.Dato3EditField.Enable = 'on';
            app.Dato3EditField.Value = 0;
            app.Dato4EditField.Enable = 'on';
            app.Dato4EditField.Value = 0;
            app.Dato5EditField.Enable = 'on';
            app.Dato5EditField.Value = 0;
            app.Dato6EditField.Enable = 'on';
            app.Dato6EditField.Value = 0;
            app.Dato7EditField.Enable = 'on';
            app.Dato7EditField.Value = 0;
            app.Dato8EditField.Enable = 'on';
            app.Dato8EditField.Value = 0;
            app.Dato9EditField.Enable = 'on';
            app.Dato9EditField.Value = 0;
            app.Dato10EditField.Enable = 'on';
            app.Dato10EditField.Value = 0;

            
            app.TallamEditField.Enable = 'on';
            app.TallamEditField.Value = 0;
            app.PesoKgEditField.Enable = 'on';
            app.PesoKgEditField.Value = 0;
            app.FrecuenciarespiratoriarespminEditField.Enable = 'on';
            app.FrecuenciarespiratoriarespminEditField.Value = 0;
            app.FrecuenciacardiacalatminEditField.Enable = 'on';
            app.FrecuenciacardiacalatminEditField.Value = 0;
            app.TensinArterialmmHgEditField.Enable = 'on';
            app.TensinArterialmmHgEditField.Value = '';
            app.TemperaturaCEditField.Enable = 'on';
            app.TemperaturaCEditField.Value = 0;

            app.HabitusexteriorEditField.Enable = 'on';
            app.HabitusexteriorEditField.Value = '';

            app.NumeroexteriorEditField.Enable = 'on';
            app.NumeroexteriorEditField.Value = '';
            app.NumerointeriorEditField.Enable = 'on';
            app.NumerointeriorEditField.Value = '';
            app.CalleEditField.Enable = 'on';
            app.CalleEditField.Value = '';
            app.ColoniaEditField.Enable = 'on';
            app.ColoniaEditField.Value = '';
            app.CodigopostalEditField.Enable = 'on';
            app.CodigopostalEditField.Value = 0;
            app.MunicipiooalcaldiaEditField.Enable = 'on';
            app.MunicipiooalcaldiaEditField.Value = '';
            app.EntidadfederativaEditField.Enable = 'on';
            app.EntidadfederativaEditField.Value = '';
            
            %Borrar datos finales
            app.NombresEditField_2.Value = '';
            app.ApellidosEditField_2.Value = '';
            app.EdadEditField_2.Value = 0;
            app.HabitusExteriorTextArea.Value = '';
            app.DomicilioTextArea.Value = '';
            app.SexoEditField.Value = '';
            app.TipodesangreEditField_2.Value = '';
            app.IMCEditField.Value = 0;
            
            %Invisible datos finales
            app.NombresEditField_2.Visible = 'off';
            app.ApellidosEditField_2.Visible = 'off';
            app.EdadEditField_2.Visible = 'off';
            app.IMCEditField.Visible = 'off';
            app.TipodesangreEditField_2.Visible = 'off';
            app.HabitusExteriorTextArea.Visible = 'off';
            app.DomicilioTextArea.Visible = 'off';
            app.SexoEditField.Visible = 'off';
            app.NombresEditField_2Label.Visible = 'off';
            app.ApellidosEditField_2Label.Visible = 'off';
            app.EdadEditField_2Label.Visible = 'off';
            app.IMCEditFieldLabel.Visible = 'off';
            app.TipodesangreEditField_2Label.Visible = 'off';
            app.HabitusExteriorTextAreaLabel.Visible = 'off';
            app.DomicilioTextAreaLabel.Visible = 'off';
            app.SexoEditFieldLabel.Visible = 'off';
            
            %Desactivar paneles
            
            app.DatosdelPacientePanel.Enable = 'off';
            app.EstudiosdelaboratorioygabinetePanel.Enable = 'off';
            app.HistoriaclinicaPanel.Enable = 'off';
            app.ExploracinFisicaPanel.Enable = 'off';
            app.DomicilioPanel.Enable = 'off';
            app.ConfirmacionPanel.Enable = 'off';

            app.NombresEditField.Enable = 'off';
            app.EdadEditField.Enable = 'off';
            app.ApellidosEditField.Enable = 'off';
            app.SexoMoFEditField.Enable = 'off';

            app.Validar.Text = '';
            app.Datos.Text = '';

            %Borrar graficas
            plot(app.ECG,0,0)
            app.ECG.Visible = 'off';
            plot(app.SaturacionOxigeno,0,0)
            app.SaturacionOxigeno.Visible = 'off';

            app.Lampconfirmacion.Color = 'r';

            %%Activar acceder con password
            app.AccederButton.Enable = 'on';
            app.ContraseaEditField.Enable = 'on';
            app.ContraseaEditField.Value = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosgeneralesPanel = uipanel(app.UIFigure);
            app.DatosgeneralesPanel.Title = 'Datos generales';
            app.NombresEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.NombresEditFieldLabel.Text = 'Nombre(s)';
            app.NombresEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.NombresEditField.Enable = 'off';
            app.ApellidosEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.ApellidosEditFieldLabel.Text = 'Apellido(s)';
            app.ApellidosEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.ApellidosEditField.Enable = 'off';
            app.EdadEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.DatosgeneralesPanel, 'numeric');
            app.EdadEditField.Enable = 'off';
            app.DomicilioPanel = uipanel(app.DatosgeneralesPanel);
            app.DomicilioPanel.Enable = 'off';
            app.DomicilioPanel.Title = 'Domicilio';
            app.EntidadfederativaEditFieldLabel = uilabel(app.DomicilioPanel);
            app.EntidadfederativaEditFieldLabel.Text = 'Entidad federativa';
            app.EntidadfederativaEditField = uieditfield(app.DomicilioPanel, 'text');
            app.MunicipiooalcaldiaEditFieldLabel = uilabel(app.DomicilioPanel);
            app.MunicipiooalcaldiaEditFieldLabel.Text = 'Municipio o alcaldia';
            app.MunicipiooalcaldiaEditField = uieditfield(app.DomicilioPanel, 'text');
            app.CodigopostalEditFieldLabel = uilabel(app.DomicilioPanel);
            app.CodigopostalEditFieldLabel.Text = 'Codigo postal';
            app.CodigopostalEditField = uieditfield(app.DomicilioPanel, 'numeric');
            app.ColoniaEditFieldLabel = uilabel(app.DomicilioPanel);
            app.ColoniaEditFieldLabel.Text = 'Colonia';
            app.ColoniaEditField = uieditfield(app.DomicilioPanel, 'text');
            app.CalleEditFieldLabel = uilabel(app.DomicilioPanel);
            app.CalleEditFieldLabel.Text = 'Calle';
            app.CalleEditField = uieditfield(app.DomicilioPanel, 'text');
            app.NumerointeriorEditFieldLabel = uilabel(app.DomicilioPanel);
            app.NumerointeriorEditFieldLabel.Text = 'Numero interior';
            app.NumerointeriorEditField = uieditfield(app.DomicilioPanel, 'text');
            app.NumeroexteriorEditFieldLabel = uilabel(app.DomicilioPanel);
            app.NumeroexteriorEditFieldLabel.Text = 'Numero exterior';
            app.NumeroexteriorEditField = uieditfield(app.DomicilioPanel, 'text');
            app.AccederButton = uibutton(app.DatosgeneralesPanel, 'push');
            app.AccederButton.ButtonPushedFcn = createCallbackFcn(app, @AccederButtonPushed, true);
            app.AccederButton.Text = 'Acceder';
            app.Validar = uilabel(app.DatosgeneralesPanel);
            app.Validar.Text = '';
            app.LampPassword = uilamp(app.DatosgeneralesPanel);
            app.CargarDatosButton = uibutton(app.DatosgeneralesPanel, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.ContraseaEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.Datos = uilabel(app.DatosgeneralesPanel);
            app.Datos.Text = {''; ''; ''};
            app.SexoMoFEditFieldLabel = uilabel(app.DatosgeneralesPanel);
            app.SexoMoFEditFieldLabel.Text = 'Sexo (M o F)';
            app.SexoMoFEditField = uieditfield(app.DatosgeneralesPanel, 'text');
            app.SexoMoFEditField.Enable = 'off';
            app.ExploracinFisicaPanel = uipanel(app.UIFigure);
            app.ExploracinFisicaPanel.Enable = 'off';
            app.ExploracinFisicaPanel.Title = 'Exploración Fisica';
            app.HabitusexteriorEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.HabitusexteriorEditFieldLabel.Text = 'Habitus exterior';
            app.HabitusexteriorEditField = uieditfield(app.ExploracinFisicaPanel, 'text');
            app.SignosvitalesLabel = uilabel(app.ExploracinFisicaPanel);
            app.SignosvitalesLabel.Text = 'Signos vitales';
            app.TemperaturaCEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.TemperaturaCEditFieldLabel.Text = 'Temperatura °C';
            app.TemperaturaCEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.TensinArterialmmHgEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.TensinArterialmmHgEditFieldLabel.Text = 'Tensión Arterial (mmHg)';
            app.TensinArterialmmHgEditField = uieditfield(app.ExploracinFisicaPanel, 'text');
            app.FrecuenciacardiacalatminEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.FrecuenciacardiacalatminEditFieldLabel.Text = 'Frecuencia cardiaca (lat/min)';
            app.FrecuenciacardiacalatminEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.FrecuenciarespiratoriarespminEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.FrecuenciarespiratoriarespminEditFieldLabel.Text = 'Frecuencia respiratoria (resp/min)';
            app.FrecuenciarespiratoriarespminEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.PesoKgEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.PesoKgEditFieldLabel.Text = 'Peso (Kg)';
            app.PesoKgEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.TallamEditFieldLabel = uilabel(app.ExploracinFisicaPanel);
            app.TallamEditFieldLabel.Text = 'Talla (m)';
            app.TallamEditField = uieditfield(app.ExploracinFisicaPanel, 'numeric');
            app.HistoriaclinicaPanel = uipanel(app.UIFigure);
            app.HistoriaclinicaPanel.Enable = 'off';
            app.HistoriaclinicaPanel.Title = 'Historia clinica';
            app.FolioEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.FolioEditFieldLabel.Visible = 'off';
            app.FolioEditFieldLabel.Text = 'Folio';
            app.FolioEditField = uieditfield(app.HistoriaclinicaPanel, 'numeric');
            app.FolioEditField.Visible = 'off';
            app.GrupoetnicoEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.GrupoetnicoEditFieldLabel.Text = 'Grupo etnico';
            app.GrupoetnicoEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.AntecendentesheredofamiliaresLabel = uilabel(app.HistoriaclinicaPanel);
            app.AntecendentesheredofamiliaresLabel.Text = 'Diabetes';
            app.DiabetesEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.AlergasEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.AlergasEditFieldLabel.Text = 'Alergías';
            app.AlergasEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.AlcoholEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.AlcoholEditFieldLabel.Text = 'Alcohol';
            app.AlcoholEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.ANTECEDENTESHEREDOFAMILIARESLabel = uilabel(app.HistoriaclinicaPanel);
            app.ANTECEDENTESHEREDOFAMILIARESLabel.Text = 'ANTECEDENTES HEREDOFAMILIARES';
            app.HipertensinArterialEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.HipertensinArterialEditFieldLabel.Text = 'Hipertensión Arterial';
            app.HipertensinArterialEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.CancerEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.CancerEditFieldLabel.Text = 'Cancer';
            app.CancerEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.TranstornospsicologicosEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.TranstornospsicologicosEditFieldLabel.Text = 'Transtornos psicologicos';
            app.TranstornospsicologicosEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.NeuropatiaEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.NeuropatiaEditFieldLabel.Text = 'Neuropatia';
            app.NeuropatiaEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.ANTECEDENTESPERSONALESPATOLOGICOSLabel = uilabel(app.HistoriaclinicaPanel);
            app.ANTECEDENTESPERSONALESPATOLOGICOSLabel.Text = 'ANTECEDENTES PERSONALES PATOLOGICOS';
            app.ANTECEDENTESPERSONALESNOPATOLOGICOSLabel = uilabel(app.HistoriaclinicaPanel);
            app.ANTECEDENTESPERSONALESNOPATOLOGICOSLabel.Text = 'ANTECEDENTES PERSONALES NO PATOLOGICOS';
            app.OtroEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.OtroEditFieldLabel.Text = 'Otro';
            app.OtroEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.TabaquismoEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.TabaquismoEditFieldLabel.Text = 'Tabaquismo';
            app.TabaquismoEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.PsicotropicoEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.PsicotropicoEditFieldLabel.Text = 'Psicotropico';
            app.PsicotropicoEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.TipodesangreEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.TipodesangreEditFieldLabel.Text = 'Tipo de sangre';
            app.TipodesangreEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.FracturasEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.FracturasEditFieldLabel.Text = 'Fracturas';
            app.FracturasEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.TransfusionesEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.TransfusionesEditFieldLabel.Text = 'Transfusiones';
            app.TransfusionesEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.CirugasEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.CirugasEditFieldLabel.Text = 'Cirugías';
            app.CirugasEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.EnferemedadcronicaEditFieldLabel = uilabel(app.HistoriaclinicaPanel);
            app.EnferemedadcronicaEditFieldLabel.Text = 'Enferemedad  cronica';
            app.EnferemedadcronicaEditField = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.OtroEditField_2Label = uilabel(app.HistoriaclinicaPanel);
            app.OtroEditField_2Label.Text = 'Otro';
            app.OtroEditField_2 = uieditfield(app.HistoriaclinicaPanel, 'text');
            app.PadecimientoactualTextAreaLabel = uilabel(app.HistoriaclinicaPanel);
            app.PadecimientoactualTextAreaLabel.Text = 'Padecimiento actual';
            app.PadecimientoactualTextArea = uitextarea(app.HistoriaclinicaPanel);
            app.TratamientospreviosTextAreaLabel = uilabel(app.HistoriaclinicaPanel);
            app.TratamientospreviosTextAreaLabel.Text = 'Tratamientos previos';
            app.TratamientospreviosTextArea = uitextarea(app.HistoriaclinicaPanel);
            app.EstudiosdelaboratorioygabinetePanel = uipanel(app.UIFigure);
            app.EstudiosdelaboratorioygabinetePanel.Enable = 'off';
            app.EstudiosdelaboratorioygabinetePanel.Title = 'Estudios de laboratorio y gabinete';
            app.ELECTROCARDIOGRAMAPanel = uipanel(app.EstudiosdelaboratorioygabinetePanel);
            app.ELECTROCARDIOGRAMAPanel.Title = 'ELECTROCARDIOGRAMA';
            app.ECG = uiaxes(app.ELECTROCARDIOGRAMAPanel);
            title(app.ECG, 'ECG Paciente')
            xlabel(app.ECG, 'Tiempo (s)')
            ylabel(app.ECG, 'Amplitud (mV)')
            app.ECG.XLim = [0 5];
            app.ECG.YLim = [0 5];
            app.ECG.ZLim = [0 5];
            app.ECG.XTick = [0 0.25 0.5 0.75 1];
            app.ECG.Visible = 'off';
            app.SATURACIONDEOXIGENOPanel = uipanel(app.EstudiosdelaboratorioygabinetePanel);
            app.SATURACIONDEOXIGENOPanel.Title = 'SATURACION DE OXIGENO';
            app.SaturacionOxigeno = uiaxes(app.SATURACIONDEOXIGENOPanel);
            title(app.SaturacionOxigeno, 'Saturación de Oxigeno')
            xlabel(app.SaturacionOxigeno, 'PO2 mmHg')
            ylabel(app.SaturacionOxigeno, '% Saturación (SO2)')
            zlabel(app.SaturacionOxigeno, 'Z')
            app.SaturacionOxigeno.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.SaturacionOxigeno.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.SaturacionOxigeno.YTick = [0 20 40 60 80 100];
            app.SaturacionOxigeno.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.SaturacionOxigeno.Visible = 'off';
            app.SaturacindeoxgenoenlasangreSO2Label = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.SaturacindeoxgenoenlasangreSO2Label.Text = 'Saturación de oxígeno en la sangre (SO2)';
            app.Dato1EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato1EditFieldLabel.Text = 'Dato1';
            app.Dato1EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato2EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato2EditFieldLabel.Text = 'Dato2';
            app.Dato2EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato3EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato3EditFieldLabel.Text = 'Dato3';
            app.Dato3EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato4EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato4EditFieldLabel.Text = 'Dato4';
            app.Dato4EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato5EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato5EditFieldLabel.Text = 'Dato5';
            app.Dato5EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato6EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato6EditFieldLabel.Text = 'Dato6';
            app.Dato6EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato7EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato7EditFieldLabel.Text = 'Dato7';
            app.Dato7EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato8EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato8EditFieldLabel.Text = 'Dato8';
            app.Dato8EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato9EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato9EditFieldLabel.Text = 'Dato9';
            app.Dato9EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.Dato10EditFieldLabel = uilabel(app.EstudiosdelaboratorioygabinetePanel);
            app.Dato10EditFieldLabel.Text = 'Dato10';
            app.Dato10EditField = uieditfield(app.EstudiosdelaboratorioygabinetePanel, 'numeric');
            app.ConfirmacionPanel = uipanel(app.UIFigure);
            app.ConfirmacionPanel.Enable = 'off';
            app.ConfirmacionPanel.Title = 'Confirmacion';
            app.ValidardatosLabel = uilabel(app.ConfirmacionPanel);
            app.ValidardatosLabel.Text = '¿Son corrector tus datos?';
            app.SoncorrectortusdatosSwitch = uiswitch(app.ConfirmacionPanel, 'slider');
            app.SoncorrectortusdatosSwitch.ValueChangedFcn = createCallbackFcn(app, @SoncorrectortusdatosSwitchValueChanged, true);
            app.Lampconfirmacion = uilamp(app.ConfirmacionPanel);
            app.DatosdelPacientePanel = uipanel(app.UIFigure);
            app.DatosdelPacientePanel.Enable = 'off';
            app.DatosdelPacientePanel.Title = 'Datos del Paciente';
            app.NombresEditField_2Label = uilabel(app.DatosdelPacientePanel);
            app.NombresEditField_2Label.Visible = 'off';
            app.NombresEditField_2Label.Text = 'Nombre(s)';
            app.NombresEditField_2 = uieditfield(app.DatosdelPacientePanel, 'text');
            app.NombresEditField_2.Visible = 'off';
            app.ApellidosEditField_2Label = uilabel(app.DatosdelPacientePanel);
            app.ApellidosEditField_2Label.Visible = 'off';
            app.ApellidosEditField_2Label.Text = 'Apellido(s)';
            app.ApellidosEditField_2 = uieditfield(app.DatosdelPacientePanel, 'text');
            app.ApellidosEditField_2.Visible = 'off';
            app.EdadEditField_2Label = uilabel(app.DatosdelPacientePanel);
            app.EdadEditField_2Label.Visible = 'off';
            app.EdadEditField_2Label.Text = 'Edad';
            app.EdadEditField_2 = uieditfield(app.DatosdelPacientePanel, 'numeric');
            app.EdadEditField_2.Visible = 'off';
            app.NuevoPacienteButton = uibutton(app.DatosdelPacientePanel, 'push');
            app.NuevoPacienteButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoPacienteButtonPushed, true);
            app.NuevoPacienteButton.Enable = 'off';
            app.NuevoPacienteButton.Text = 'Nuevo Paciente';
            app.DomicilioTextAreaLabel = uilabel(app.DatosdelPacientePanel);
            app.DomicilioTextAreaLabel.Visible = 'off';
            app.DomicilioTextAreaLabel.Text = 'Domicilio';
            app.DomicilioTextArea = uitextarea(app.DatosdelPacientePanel);
            app.DomicilioTextArea.Visible = 'off';
            app.HabitusExteriorTextAreaLabel = uilabel(app.DatosdelPacientePanel);
            app.HabitusExteriorTextAreaLabel.Visible = 'off';
            app.HabitusExteriorTextAreaLabel.Text = 'Habitus Exterior';
            app.HabitusExteriorTextArea = uitextarea(app.DatosdelPacientePanel);
            app.HabitusExteriorTextArea.Visible = 'off';
            app.SexoEditFieldLabel = uilabel(app.DatosdelPacientePanel);
            app.SexoEditFieldLabel.Visible = 'off';
            app.SexoEditFieldLabel.Text = 'Sexo';
            app.SexoEditField = uieditfield(app.DatosdelPacientePanel, 'text');
            app.SexoEditField.Visible = 'off';
            app.TipodesangreEditField_2Label = uilabel(app.DatosdelPacientePanel);
            app.TipodesangreEditField_2Label.Visible = 'off';
            app.TipodesangreEditField_2Label.Text = 'Tipo de sangre';
            app.TipodesangreEditField_2 = uieditfield(app.DatosdelPacientePanel, 'text');
            app.TipodesangreEditField_2.Visible = 'off';
            app.IMCEditFieldLabel = uilabel(app.DatosdelPacientePanel);
            app.IMCEditFieldLabel.Visible = 'off';
            app.IMCEditFieldLabel.Text = 'IMC';
            app.IMCEditField = uieditfield(app.DatosdelPacientePanel, 'numeric');
            app.IMCEditField.Editable = 'off';
            app.IMCEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Expediente_Clinico1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end