classdef Velocidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        EQUIPOLabel                  matlab.ui.control.Label
        AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel  matlab.ui.control.Label
        CalculadoradeVelocidadLabel  matlab.ui.control.Label
        EvaluacindevelocidadPanel    matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        EvaluacinvelocidadEditField  matlab.ui.control.NumericEditField
        EvaluacinvelocidadEditFieldLabel  matlab.ui.control.Label
        ENTRADASPanel                matlab.ui.container.Panel
        VelocidadmsEditField         matlab.ui.control.NumericEditField
        VelocidadmsEditFieldLabel    matlab.ui.control.Label
        TiemposEditField             matlab.ui.control.NumericEditField
        TiemposEditFieldLabel        matlab.ui.control.Label
        DistanciamEditField          matlab.ui.control.NumericEditField
        DistanciamEditFieldLabel     matlab.ui.control.Label
        CalculoButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculoButton
        function CalculoButtonPushed(app, event)
            D = app.DistanciamEditField.Value;
            T = app.TiemposEditField.Value;
            V = D/T;
            app.VelocidadmsEditField.Value = V;
          
        end

        % Value changed function: EvaluacinvelocidadEditField
        function EvaluacinvelocidadEditFieldValueChanged(app, event)
            value = app.EvaluacinvelocidadEditField.Value;
            if value == 0
                v = 0;
            elseif value > 0 && value <= 20
                v = 1;
            elseif value > 21 && value <= 100
                v = 2;
            else
                v = 3;
            end

            switch v
                case 0
                    app.Lamp.Color = [0.90 0.90 0.90];
                    app.Label.Text = 'Velocidad nula';
                case 1
                    app.Lamp.Color = [0 1 0];
                    app.Label.Text = 'Velocidad baja';
                case 2
                    app.Lamp.Color = [1 0 0];
                    app.Label.Text = 'Velocidad media';
                otherwise
                    app.Lamp.Color = [1 0.80 0.20];
                    app.Label.Text = 'Velocidad alta';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ENTRADASPanel = uipanel(app.UIFigure);
            app.ENTRADASPanel.Title = 'ENTRADAS';
            app.CalculoButton = uibutton(app.ENTRADASPanel, 'push');
            app.CalculoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculoButtonPushed, true);
            app.CalculoButton.Text = 'Calculo';
            app.DistanciamEditFieldLabel = uilabel(app.ENTRADASPanel);
            app.DistanciamEditFieldLabel.Text = 'Distancia (m)';
            app.DistanciamEditField = uieditfield(app.ENTRADASPanel, 'numeric');
            app.TiemposEditFieldLabel = uilabel(app.ENTRADASPanel);
            app.TiemposEditFieldLabel.Text = 'Tiempo (s)';
            app.TiemposEditField = uieditfield(app.ENTRADASPanel, 'numeric');
            app.VelocidadmsEditFieldLabel = uilabel(app.ENTRADASPanel);
            app.VelocidadmsEditFieldLabel.Text = 'Velocidad (m/s)';
            app.VelocidadmsEditField = uieditfield(app.ENTRADASPanel, 'numeric');
            app.EvaluacindevelocidadPanel = uipanel(app.UIFigure);
            app.EvaluacindevelocidadPanel.Title = 'Evaluación de velocidad';
            app.EvaluacinvelocidadEditFieldLabel = uilabel(app.EvaluacindevelocidadPanel);
            app.EvaluacinvelocidadEditFieldLabel.Text = 'Evaluación velocidad';
            app.EvaluacinvelocidadEditField = uieditfield(app.EvaluacindevelocidadPanel, 'numeric');
            app.EvaluacinvelocidadEditField.ValueChangedFcn = createCallbackFcn(app, @EvaluacinvelocidadEditFieldValueChanged, true);
            app.Lamp = uilamp(app.EvaluacindevelocidadPanel);
            app.Label = uilabel(app.EvaluacindevelocidadPanel);
            app.Label.Text = '';
            app.CalculadoradeVelocidadLabel = uilabel(app.UIFigure);
            app.CalculadoradeVelocidadLabel.Text = 'Calculadora de Velocidad';
            app.Panel = uipanel(app.UIFigure);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel = uilabel(app.Panel);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel.Text = {'García Espinosa Kibsaim'; 'Gaspar Ortiz Carlos Emilio'; 'Hernandez Moncada Cristofer'};
            app.EQUIPOLabel = uilabel(app.Panel);
            app.EQUIPOLabel.Text = 'EQUIPO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Velocidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end