classdef P9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        PortadaTab                  matlab.ui.container.Tab
        GarciaEspinosaKibsaimLabel  matlab.ui.control.Label
        UNIDADPROFESIONALINTERDISCIPLINARIADEBIOTECNOLOGIALabel  matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        INSTITUTOPOLITECNICONACIONALLabel  matlab.ui.control.Label
        GraficaTab                  matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        RectaCheckBox               matlab.ui.control.CheckBox
        RectavariableCheckBox       matlab.ui.control.CheckBox
        Panel3                      matlab.ui.container.Panel
        Button2                     matlab.ui.control.Button
        offsetSlider                matlab.ui.control.Slider
        offsetSliderLabel           matlab.ui.control.Label
        PendienteSlider             matlab.ui.control.Slider
        PendienteSliderLabel        matlab.ui.control.Label
        Panel2                      matlab.ui.container.Panel
        Button                      matlab.ui.control.Button
        PendienteEditField          matlab.ui.control.NumericEditField
        PendienteEditFieldLabel     matlab.ui.control.Label
        OffsetEditField             matlab.ui.control.NumericEditField
        OffsetEditFieldLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RectaCheckBox
        function RectaCheckBoxValueChanged(app, event)
            value = app.RectaCheckBox.Value;
            
            app.OffsetEditField.Enable = 'on';
            app.PendienteEditField.Enable = 'on';
            app.Button.Enable = 'on'

            app.offsetSlider.Enable = 'off';
            app.PendienteSlider.Enable = 'off';
            app.Button2.Enable = 'off'
        end

        % Value changed function: RectavariableCheckBox
        function RectavariableCheckBoxValueChanged(app, event)
            value = app.RectavariableCheckBox.Value;
            
            app.offsetSlider.Enable = 'on';
            app.PendienteSlider.Enable = 'on';
            app.Button2.Enable = 'on'

            app.OffsetEditField.Enable = 'off';
            app.PendienteEditField.Enable = 'off';
            app.Button.Enable = 'off'
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            m = app.PendienteEditField.Value;
            b = app.OffsetEditField.Value;

            x = 0:0.1:100;
            y = (m*x) + b;
            plot(app.UIAxes,y);
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            m = app.PendienteSlider.Value;
            b = app.offsetSlider.Value;

            x = 0:0.1:100;
            y = (m*x) + b;
            plot(app.UIAxes,y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PortadaTab = uitab(app.TabGroup);
            app.PortadaTab.Title = 'Portada';
            app.INSTITUTOPOLITECNICONACIONALLabel = uilabel(app.PortadaTab);
            app.INSTITUTOPOLITECNICONACIONALLabel.Text = 'INSTITUTO POLITECNICO NACIONAL';
            app.Image = uiimage(app.PortadaTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-ipn.png');
            app.Image2 = uiimage(app.PortadaTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'upibi.jpeg');
            app.UNIDADPROFESIONALINTERDISCIPLINARIADEBIOTECNOLOGIALabel = uilabel(app.PortadaTab);
            app.UNIDADPROFESIONALINTERDISCIPLINARIADEBIOTECNOLOGIALabel.Text = 'UNIDAD PROFESIONAL INTERDISCIPLINARIA DE BIOTECNOLOGIA';
            app.GarciaEspinosaKibsaimLabel = uilabel(app.PortadaTab);
            app.GarciaEspinosaKibsaimLabel.Text = {'Garcia Espinosa Kibsaim'; 'Gaspar Ortiz Carlos Emilio'; 'Hernandez Moncada Cristofer'};
            app.GraficaTab = uitab(app.TabGroup);
            app.GraficaTab.Title = 'Grafica';
            app.UIAxes = uiaxes(app.GraficaTab);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Magnitud')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GraficaTab);
            app.Panel.Title = 'Panel';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = 'Panel2';
            app.OffsetEditFieldLabel = uilabel(app.Panel2);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.Panel2, 'numeric');
            app.PendienteEditFieldLabel = uilabel(app.Panel2);
            app.PendienteEditFieldLabel.Text = 'Pendiente';
            app.PendienteEditField = uieditfield(app.Panel2, 'numeric');
            app.Button = uibutton(app.Panel2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Panel3 = uipanel(app.Panel);
            app.Panel3.Title = 'Panel3';
            app.PendienteSliderLabel = uilabel(app.Panel3);
            app.PendienteSliderLabel.Text = 'Pendiente';
            app.PendienteSlider = uislider(app.Panel3);
            app.offsetSliderLabel = uilabel(app.Panel3);
            app.offsetSliderLabel.Text = 'offset';
            app.offsetSlider = uislider(app.Panel3);
            app.Button2 = uibutton(app.Panel3, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.RectavariableCheckBox = uicheckbox(app.Panel);
            app.RectavariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectavariableCheckBoxValueChanged, true);
            app.RectavariableCheckBox.Text = 'Recta variable';
            app.RectaCheckBox = uicheckbox(app.Panel);
            app.RectaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectaCheckBoxValueChanged, true);
            app.RectaCheckBox.Text = 'Recta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end