classdef Switch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Switch3                    matlab.ui.control.Switch
        Switch3Label               matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        EQUIPOLabel                matlab.ui.control.Label
        AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel  matlab.ui.control.Label
        PaneldecontroldeLEDSLabel  matlab.ui.control.Label
        LED2Panel                  matlab.ui.container.Panel
        LED2                       matlab.ui.control.Lamp
        LED2LampLabel              matlab.ui.control.Label
        Switch2                    matlab.ui.control.ToggleSwitch
        Switch2Label               matlab.ui.control.Label
        LED1Panel                  matlab.ui.container.Panel
        LED1                       matlab.ui.control.Lamp
        LED1LampLabel              matlab.ui.control.Label
        Switch_2                   matlab.ui.control.RockerSwitch
        Switch_2Label              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value,'On') %AQUI SE MODIFICA PROPIEDAD DE SER EDITABLE
                app.LED1.Color = 'g';
            else
                app.LED1.Color = 'r';
            end
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            if strcmp(value,'On') 
                app.LED2.Color = 'b';
            else
                app.LED2.Color = 'black';
            end
        end

        % Value changed function: Switch3
        function Switch3ValueChanged(app, event)
            value = app.Switch3.Value;
            if strcmp(value,'On') 
                
            else
                app.LED1.Color = 'black';
                app.LED2.Color = 'black';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LED1Panel = uipanel(app.UIFigure);
            app.LED1Panel.Title = 'LED 1';
            app.Switch_2Label = uilabel(app.LED1Panel);
            app.Switch_2Label.Text = 'Switch';
            app.Switch_2 = uiswitch(app.LED1Panel, 'rocker');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.LED1LampLabel = uilabel(app.LED1Panel);
            app.LED1LampLabel.Text = 'LED1';
            app.LED1 = uilamp(app.LED1Panel);
            app.LED2Panel = uipanel(app.UIFigure);
            app.LED2Panel.Title = 'LED 2';
            app.Switch2Label = uilabel(app.LED2Panel);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.LED2Panel, 'toggle');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.LED2LampLabel = uilabel(app.LED2Panel);
            app.LED2LampLabel.Text = 'LED2';
            app.LED2 = uilamp(app.LED2Panel);
            app.PaneldecontroldeLEDSLabel = uilabel(app.UIFigure);
            app.PaneldecontroldeLEDSLabel.Text = 'Panel de control de LEDS';
            app.Panel = uipanel(app.UIFigure);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel = uilabel(app.Panel);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel.Text = {'García Espinosa Kibsaim'; 'Gaspar Ortiz Carlos Emilio'; 'Hernandez Moncada Cristofer'};
            app.EQUIPOLabel = uilabel(app.Panel);
            app.EQUIPOLabel.Text = 'EQUIPO';
            app.Switch3Label = uilabel(app.UIFigure);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.UIFigure, 'slider');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @Switch3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Switch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end