classdef IMC_Evaluacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        EQUIPOLabel            matlab.ui.control.Label
        AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel  matlab.ui.control.Label
        CalculadoradeIMCLabel  matlab.ui.control.Label
        SALIDASPanel           matlab.ui.container.Panel
        INDICADORLamp          matlab.ui.control.Lamp
        INDICADORLampLabel     matlab.ui.control.Label
        IMCEditField           matlab.ui.control.NumericEditField
        IMCEditFieldLabel      matlab.ui.control.Label
        ENTRADASPanel          matlab.ui.container.Panel
        PesokgEditField        matlab.ui.control.NumericEditField
        PesokgEditFieldLabel   matlab.ui.control.Label
        AlturamEditField       matlab.ui.control.NumericEditField
        AlturamEditFieldLabel  matlab.ui.control.Label
        CalcularButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            P = app.PesokgEditField.Value;
            A = app.AlturamEditField.Value;
            IMC = P/A^2;
            app.IMCEditField.Value = 2;
          
            if IMC < 18.5
                app.INDICADORLamp.Color = 'k';
            elseif IMC >= 18.5 && IMC <= 24.9
                app.INDICADORLamp.Color = 'g';
            elseif IMC > 24.9 && IMC <= 30
                app.INDICADORLamp.Color = 'o'
            else
                app.INDICADORLamp.Color = 'r'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ENTRADASPanel = uipanel(app.UIFigure);
            app.ENTRADASPanel.Title = 'ENTRADAS';
            app.CalcularButton = uibutton(app.ENTRADASPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.AlturamEditFieldLabel = uilabel(app.ENTRADASPanel);
            app.AlturamEditFieldLabel.Text = 'Altura (m)';
            app.AlturamEditField = uieditfield(app.ENTRADASPanel, 'numeric');
            app.PesokgEditFieldLabel = uilabel(app.ENTRADASPanel);
            app.PesokgEditFieldLabel.Text = 'Peso (kg)';
            app.PesokgEditField = uieditfield(app.ENTRADASPanel, 'numeric');
            app.SALIDASPanel = uipanel(app.UIFigure);
            app.SALIDASPanel.Title = 'SALIDAS';
            app.IMCEditFieldLabel = uilabel(app.SALIDASPanel);
            app.IMCEditFieldLabel.Text = 'IMC';
            app.IMCEditField = uieditfield(app.SALIDASPanel, 'numeric');
            app.INDICADORLampLabel = uilabel(app.SALIDASPanel);
            app.INDICADORLampLabel.Text = 'INDICADOR';
            app.INDICADORLamp = uilamp(app.SALIDASPanel);
            app.CalculadoradeIMCLabel = uilabel(app.UIFigure);
            app.CalculadoradeIMCLabel.Text = 'Calculadora de IMC';
            app.Panel = uipanel(app.UIFigure);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel = uilabel(app.Panel);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel.Text = {'García Espinosa Kibsaim'; 'Gaspar Ortiz Carlos Emilio'; 'Hernandez Moncada Cristofer'};
            app.EQUIPOLabel = uilabel(app.Panel);
            app.EQUIPOLabel.Text = 'EQUIPO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMC_Evaluacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end