classdef Switch_DosLEDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LED3Panel                  matlab.ui.container.Panel
        Switch3                    matlab.ui.control.Switch
        Switch3Label               matlab.ui.control.Label
        LED3                       matlab.ui.control.Lamp
        LED3Label                  matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        EQUIPOLabel                matlab.ui.control.Label
        AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel  matlab.ui.control.Label
        PaneldecontroldeLEDSLabel  matlab.ui.control.Label
        LED2Panel                  matlab.ui.container.Panel
        LED2                       matlab.ui.control.Lamp
        LED2LampLabel              matlab.ui.control.Label
        Switch2                    matlab.ui.control.ToggleSwitch
        Switch2Label               matlab.ui.control.Label
        LED1Panel                  matlab.ui.container.Panel
        Switch1                    matlab.ui.control.RockerSwitch
        Switch1Label               matlab.ui.control.Label
        LED1                       matlab.ui.control.Lamp
        LED1LampLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch1
        function Switch1ValueChanged(app, event)
            value = app.Switch1.Value;
            if strcmp(value,'On') 
                app.LED1.Color = 'g';
            else
                app.LED1.Color = 'k';
            end
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            if strcmp(value,'On') 
                app.LED2.Color = 'y';
            else
                app.LED2.Color = 'k';
            end
           
        end

        % Value changed function: Switch3
        function Switch3ValueChanged(app, event)
            value = app.Switch3.Value;
            if strcmp(value,'On') 
                app.LED3.Color = 'r';
            else
                app.LED3.Color = 'k';
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LED1Panel = uipanel(app.UIFigure);
            app.LED1Panel.Title = 'LED 1';
            app.LED1LampLabel = uilabel(app.LED1Panel);
            app.LED1LampLabel.Text = 'LED1';
            app.LED1 = uilamp(app.LED1Panel);
            app.Switch1Label = uilabel(app.LED1Panel);
            app.Switch1Label.Text = 'Switch1';
            app.Switch1 = uiswitch(app.LED1Panel, 'rocker');
            app.Switch1.ValueChangedFcn = createCallbackFcn(app, @Switch1ValueChanged, true);
            app.LED2Panel = uipanel(app.UIFigure);
            app.LED2Panel.Title = 'LED 2';
            app.Switch2Label = uilabel(app.LED2Panel);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.LED2Panel, 'toggle');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.LED2LampLabel = uilabel(app.LED2Panel);
            app.LED2LampLabel.Text = 'LED2';
            app.LED2 = uilamp(app.LED2Panel);
            app.PaneldecontroldeLEDSLabel = uilabel(app.UIFigure);
            app.PaneldecontroldeLEDSLabel.Text = 'Panel de control de LEDS';
            app.Panel = uipanel(app.UIFigure);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel = uilabel(app.Panel);
            app.AlvaEstradaKibsaimGasparOrtizCarlosEmilioMoncadaCristoferLabel.Text = {'García Espinosa Kibsaim'; 'Gaspar Ortiz Carlos Emilio'; 'Hernandez Moncada Cristofer'};
            app.EQUIPOLabel = uilabel(app.Panel);
            app.EQUIPOLabel.Text = 'EQUIPO';
            app.LED3Panel = uipanel(app.UIFigure);
            app.LED3Panel.Title = 'LED 3';
            app.LED3Label = uilabel(app.LED3Panel);
            app.LED3Label.Text = 'LED3';
            app.LED3 = uilamp(app.LED3Panel);
            app.Switch3Label = uilabel(app.LED3Panel);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.LED3Panel, 'slider');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @Switch3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Switch_DosLEDS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end