classdef Recepcion_Arduino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button_3              matlab.ui.control.Button
        DigitalValuePanel     matlab.ui.container.Panel
        ValueLabel            matlab.ui.control.Label
        DigitalValueLabel     matlab.ui.control.Label
        samplenumberLabel     matlab.ui.control.Label
        SampleLabel           matlab.ui.control.Label
        DatosPanel            matlab.ui.container.Panel
        DetenerButton         matlab.ui.control.Button
        Tree                  matlab.ui.container.Tree
        LimitadosNode         matlab.ui.container.TreeNode
        Node_2                matlab.ui.container.TreeNode
        Node_3                matlab.ui.container.TreeNode
        IlimitadosNode        matlab.ui.container.TreeNode
        DesconexionButton     matlab.ui.control.Button
        ConectarButton        matlab.ui.control.Button
        SinconexionLamp       matlab.ui.control.Lamp
        SinconexionLampLabel  matlab.ui.control.Label
        VisualizacionPanel    matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        ConfiguracionPanel    matlab.ui.container.Panel
        EditField             matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        VelocidadButtonGroup  matlab.ui.container.ButtonGroup
        Button_2              matlab.ui.control.RadioButton
        Button                matlab.ui.control.RadioButton
        PuertoCOMButtonGroup  matlab.ui.container.ButtonGroup
        COM6Button            matlab.ui.control.ToggleButton
        COM5Button            matlab.ui.control.ToggleButton
        COM4Button            matlab.ui.control.ToggleButton
        ContextMenu           matlab.ui.container.ContextMenu
        Menu                  matlab.ui.container.Menu
        Menu_3                matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
        ContextMenu2          matlab.ui.container.ContextMenu
        Menu_2                matlab.ui.container.Menu
        Menu2_2               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        a; % Description
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfindall);
            app.a = 0;
            
            
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            
            %value = app.ConectarButton.Value;
            puerto = app.PuertoCOMButtonGroup.SelectedObject; %Abrir cajon
            com = puerto.Text; %Sacar contenido

            velocity = app.VelocidadButtonGroup.SelectedObject;
            bauds = str2num(velocity.Text);

            samples = app.Tree.SelectedNodes.Text;


            obj1 = instrfind('Type','serial','BaudRate',9600,'Port',com);
            set(obj1,'BaudRate',bauds); %fija valor

            if isempty(obj1)
                obj1 = serial(com);
            else
                fclose(obj1);
                obj1 = obj1(1);
            end
                
            %Comenzar comunicación
            fopen(obj1);
            datavector = 0;

            if strcmp(samples,'Ilimitados')
                app.a = 1;
                
                i = 1;
                
                while app.a == 1%app.changingValue == 1
                        data = fscanf(obj1);
                        app.ValueLabel.Text = data;
                        app.samplenumberLabel.Text = num2str(i);
                        datavector = cat(2,datavector,str2num(data));
                        if length(datavector) > 100
                            grafica = datavector(i-99:i);
                            plot(app.UIAxes,grafica);
                        else
                            plot(app.UIAxes,datavector);
                        end
                        i = i + 1;
                        pause(1/1000);
                        
                end
               
            else
                app.UIAxes.XLim = [0,str2num(samples)];
                app.UIAxes.YLim = [0, 1023];
                for i=1:1:str2num(samples)
                    data = fscanf(obj1);
                    app.ValueLabel.Text = data;
                    app.samplenumberLabel.Text = num2str(i);
                    datavector = cat(2,datavector,str2num(data));
                    plot(app.UIAxes,datavector);
                    pause(1/1000);
                    
                
                end
            end
            fclose(obj1);


        end

        % Button pushed function: DesconexionButton
        function DesconexionButtonPushed(app, event)
            delete(instrfindall);
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.a = 10;
            app.EditField.Value = app.a;
            
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.a = 10;
            app.EditField.Value = app.a;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConfiguracionPanel = uipanel(app.UIFigure);
            app.ConfiguracionPanel.Title = 'Configuracion';
            app.PuertoCOMButtonGroup = uibuttongroup(app.ConfiguracionPanel);
            app.PuertoCOMButtonGroup.Title = 'Puerto COM';
            app.COM4Button = uitogglebutton(app.PuertoCOMButtonGroup);
            app.COM4Button.Text = 'COM4';
            app.COM4Button.Value = true;
            app.COM5Button = uitogglebutton(app.PuertoCOMButtonGroup);
            app.COM5Button.Text = 'COM5';
            app.COM6Button = uitogglebutton(app.PuertoCOMButtonGroup);
            app.COM6Button.Text = 'COM6';
            app.VelocidadButtonGroup = uibuttongroup(app.ConfiguracionPanel);
            app.VelocidadButtonGroup.Title = 'Velocidad';
            app.Button = uiradiobutton(app.VelocidadButtonGroup);
            app.Button.Text = '9600';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.VelocidadButtonGroup);
            app.Button_2.Text = '38400';
            app.EditFieldLabel = uilabel(app.ConfiguracionPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.ConfiguracionPanel, 'numeric');
            app.VisualizacionPanel = uipanel(app.UIFigure);
            app.VisualizacionPanel.Title = 'Visualizacion';
            app.UIAxes = uiaxes(app.VisualizacionPanel);
            title(app.UIAxes, 'Potenciometro')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Digital Value')
            zlabel(app.UIAxes, 'Z')
            app.DatosPanel = uipanel(app.UIFigure);
            app.DatosPanel.Title = 'Datos';
            app.SinconexionLampLabel = uilabel(app.DatosPanel);
            app.SinconexionLampLabel.Text = 'Sin conexion';
            app.SinconexionLamp = uilamp(app.DatosPanel);
            app.ConectarButton = uibutton(app.DatosPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconexionButton = uibutton(app.DatosPanel, 'push');
            app.DesconexionButton.ButtonPushedFcn = createCallbackFcn(app, @DesconexionButtonPushed, true);
            app.DesconexionButton.Text = 'Desconexion';
            app.Tree = uitree(app.DatosPanel);
            app.LimitadosNode = uitreenode(app.Tree);
            app.LimitadosNode.Text = 'Limitados';
            app.Node_2 = uitreenode(app.LimitadosNode);
            app.Node_2.Text = '5000';
            app.Node_3 = uitreenode(app.LimitadosNode);
            app.Node_3.Text = '1000';
            app.IlimitadosNode = uitreenode(app.LimitadosNode);
            app.IlimitadosNode.Text = 'Ilimitados';
            app.DetenerButton = uibutton(app.DatosPanel, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.DigitalValuePanel = uipanel(app.UIFigure);
            app.DigitalValuePanel.Title = 'Digital Value';
            app.SampleLabel = uilabel(app.DigitalValuePanel);
            app.SampleLabel.Text = 'Sample:';
            app.samplenumberLabel = uilabel(app.DigitalValuePanel);
            app.samplenumberLabel.Text = 'sample number';
            app.DigitalValueLabel = uilabel(app.DigitalValuePanel);
            app.DigitalValueLabel.Text = 'Digital Value:';
            app.ValueLabel = uilabel(app.DigitalValuePanel);
            app.ValueLabel.Text = 'Value';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.ConfiguracionPanel.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.UIFigure.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recepcion_Arduino

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end