classdef Expediente_Clinico_2do < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Expediente                      matlab.ui.Figure
        InicioMenu                      matlab.ui.container.Menu
        CerrarsesinMenu                 matlab.ui.container.Menu
        VisualizacinMenu                matlab.ui.container.Menu
        ClaroMenu                       matlab.ui.container.Menu
        OscuroMenu                      matlab.ui.container.Menu
        Panel_12                        matlab.ui.container.Panel
        Lampconfirmacion                matlab.ui.control.Lamp
        Validardatos                    matlab.ui.control.Switch
        SoncorrectortusdatosSwitchLabel  matlab.ui.control.Label
        FolioEditField                  matlab.ui.control.NumericEditField
        FolioEditFieldLabel             matlab.ui.control.Label
        IrafotografiaButton             matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        EXPEDIENTECLNICOELECTRNICOLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        DatosgeneralesTab               matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        GenerarCURPButton               matlab.ui.control.Button
        CargarDatosButton               matlab.ui.control.Button
        SexoDropDown                    matlab.ui.control.DropDown
        SexoDropDownLabel               matlab.ui.control.Label
        LugardeNacimientoDropDown       matlab.ui.control.DropDown
        LugardeNacimientoDropDownLabel  matlab.ui.control.Label
        CURPEditField                   matlab.ui.control.EditField
        CURPEditFieldLabel              matlab.ui.control.Label
        FechadenacimientoDatePicker     matlab.ui.control.DatePicker
        FechadenacimientoDatePickerLabel  matlab.ui.control.Label
        Image3_2                        matlab.ui.control.Image
        DomicilioLabel                  matlab.ui.control.Label
        InformacindelpacienteLabel      matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        ApellidoMaternoEditField        matlab.ui.control.EditField
        ApellidoMaternoLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        CodigopostalEditField           matlab.ui.control.EditField
        CodigopostalEditField_2Label    matlab.ui.control.Label
        EntidadFederativaDropDown       matlab.ui.control.DropDown
        EntidadFederativaDropDownLabel  matlab.ui.control.Label
        NumeroexteriorEditField         matlab.ui.control.EditField
        NumeroexteriorEditFieldLabel    matlab.ui.control.Label
        NumerointeriorEditField         matlab.ui.control.EditField
        NumerointeriorEditFieldLabel    matlab.ui.control.Label
        CalleEditField                  matlab.ui.control.EditField
        CalleEditFieldLabel             matlab.ui.control.Label
        ColoniaEditField                matlab.ui.control.EditField
        ColoniaEditFieldLabel           matlab.ui.control.Label
        MunicipiooalcaldiaEditField     matlab.ui.control.EditField
        MunicipiooalcaldiaEditFieldLabel  matlab.ui.control.Label
        EdadEditField_3                 matlab.ui.control.NumericEditField
        EdadEditField_3Label            matlab.ui.control.Label
        ApellidoPaternoEditField        matlab.ui.control.EditField
        ApellidoPaternoLabel            matlab.ui.control.Label
        NombresEditField_3              matlab.ui.control.EditField
        NombresLabel                    matlab.ui.control.Label
        HistoriaClnicaTab               matlab.ui.container.Tab
        PadecimientoactualEditField     matlab.ui.control.EditField
        PadecimientoactualEditFieldLabel  matlab.ui.control.Label
        TratamientosPreviosEditField    matlab.ui.control.EditField
        TratamientosPreviosEditFieldLabel  matlab.ui.control.Label
        TipodesangreEditField_3         matlab.ui.control.EditField
        TipodesangreEditField_3Label    matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        Panel_9                         matlab.ui.container.Panel
        OtroEditField_4                 matlab.ui.control.EditField
        OtroEditField_4Label            matlab.ui.control.Label
        CulEditField_4                  matlab.ui.control.EditField
        CulEditField_4Label             matlab.ui.control.Label
        CulEditField_3                  matlab.ui.control.EditField
        CulEditField_3Label             matlab.ui.control.Label
        LupusCheckBox_2                 matlab.ui.control.CheckBox
        TransplantesCheckBox_2          matlab.ui.control.CheckBox
        CirugiasCheckBox_2              matlab.ui.control.CheckBox
        VIHCheckBox_2                   matlab.ui.control.CheckBox
        TranstornosendocrinosCheckBox_2  matlab.ui.control.CheckBox
        EnfermedadescardiacasCheckBox_2  matlab.ui.control.CheckBox
        HipertensinCheckBox_3           matlab.ui.control.CheckBox
        DiabetestipoIICheckBox_3        matlab.ui.control.CheckBox
        DiabetestipoICheckBox_4         matlab.ui.control.CheckBox
        GrupoetnicoEditField            matlab.ui.control.EditField
        GrupoetnicoEditFieldLabel       matlab.ui.control.Label
        AntecendetespersonalesnopatologicosLabel  matlab.ui.control.Label
        AntecendetespersonalespatologicosLabel  matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        Panel_7                         matlab.ui.container.Panel
        AlimentacinDropDown             matlab.ui.control.DropDown
        AlimentacinDropDownLabel        matlab.ui.control.Label
        NivelactivafsicaDropDown        matlab.ui.control.DropDown
        NivelactivafsicaDropDownLabel   matlab.ui.control.Label
        SustanciaspsicoactivasCheckBox  matlab.ui.control.CheckBox
        TabacoCheckBox                  matlab.ui.control.CheckBox
        AlcoholCheckBox                 matlab.ui.control.CheckBox
        AntecendetesheredofamiliaresLabel  matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        QuinEditField_7                 matlab.ui.control.EditField
        QuinEditField_7Label            matlab.ui.control.Label
        QuinEditField_6                 matlab.ui.control.EditField
        QuinEditField_6Label            matlab.ui.control.Label
        QuinEditField_5                 matlab.ui.control.EditField
        QuinEditField_5Label            matlab.ui.control.Label
        QuinEditField_4                 matlab.ui.control.EditField
        QuinEditField_4Label            matlab.ui.control.Label
        QuinEditField_3                 matlab.ui.control.EditField
        QuinEditField_3Label            matlab.ui.control.Label
        QuinEditField_2                 matlab.ui.control.EditField
        QuinEditField_2Label            matlab.ui.control.Label
        QuinEditField                   matlab.ui.control.EditField
        QuinEditFieldLabel              matlab.ui.control.Label
        OtroEditField_5                 matlab.ui.control.EditField
        OtroEditField_5Label            matlab.ui.control.Label
        TipoEditField                   matlab.ui.control.EditField
        TipoEditFieldLabel              matlab.ui.control.Label
        FibrosisquisticaCheckBox        matlab.ui.control.CheckBox
        ParkinsonCheckBox               matlab.ui.control.CheckBox
        CancerCheckBox                  matlab.ui.control.CheckBox
        DiabetestipoIICheckBox          matlab.ui.control.CheckBox
        DiabetestipoICheckBox           matlab.ui.control.CheckBox
        HipertensinCheckBox             matlab.ui.control.CheckBox
        EnfermedadescardiacasCheckBox   matlab.ui.control.CheckBox
        ExploracinFisicaTab             matlab.ui.container.Tab
        TomarSignosVitalesButton        matlab.ui.control.Button
        HabitusexteriorEditField        matlab.ui.control.EditField
        HabitusexteriorEditFieldLabel   matlab.ui.control.Label
        IMCEditField_2                  matlab.ui.control.NumericEditField
        IMCEditField_2Label             matlab.ui.control.Label
        TallamEditField                 matlab.ui.control.NumericEditField
        TallamEditFieldLabel            matlab.ui.control.Label
        PesoKgEditField                 matlab.ui.control.NumericEditField
        PesoKgEditFieldLabel            matlab.ui.control.Label
        SIGNOSVITALESLabel              matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        Panel3                          matlab.ui.container.Panel
        FrecuenciarespiratoriarespminEditField  matlab.ui.control.NumericEditField
        FrecuenciarespiratoriarespminEditFieldLabel  matlab.ui.control.Label
        FrecuenciacardiacalatminEditField  matlab.ui.control.NumericEditField
        FrecuenciacardiacalatminEditFieldLabel  matlab.ui.control.Label
        TensinArterialmmHgEditField     matlab.ui.control.EditField
        TensinArterialmmHgEditFieldLabel  matlab.ui.control.Label
        TemperaturaPanel                matlab.ui.container.Panel
        GraphTemperatura                matlab.ui.control.UIAxes
        TemperaturaCEditField           matlab.ui.control.NumericEditField
        TemperaturaCEditFieldLabel      matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        Slider2                         matlab.ui.control.Slider
        Slider2Label                    matlab.ui.control.Label
        SATURACIONDEOXIGENOPanel        matlab.ui.container.Panel
        GraphSaturacionOxigeno          matlab.ui.control.UIAxes
        ELECTROCARDIOGRAMAPanel         matlab.ui.container.Panel
        GraphECG                        matlab.ui.control.UIAxes
        FotografaTab                    matlab.ui.container.Tab
        EditField                       matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        FotografadelpacienteLabel       matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        GuardarfotoButton               matlab.ui.control.Button
        UIAxesFoto                      matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        TomarfotoButton                 matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        UIAxes_Preview                  matlab.ui.control.UIAxes
        DatosFinalesTab                 matlab.ui.container.Tab
        DatosdelPacientePanel           matlab.ui.container.Panel
        FolioLabel                      matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        Panel_14                        matlab.ui.container.Panel
        GraphECG_2                      matlab.ui.control.UIAxes
        FrecrespLabel                   matlab.ui.control.Label
        TemperaturaLabel                matlab.ui.control.Label
        FreccardLabel                   matlab.ui.control.Label
        ANTECENDETESPERSONALESNOPATOLOGICOSLabel  matlab.ui.control.Label
        ANTECENDETESPERSONALESPATOLOGICOSLabel  matlab.ui.control.Label
        ANTECENDETESHEREDOFAMILIARESLabel  matlab.ui.control.Label
        DOMICILIOLabel                  matlab.ui.control.Label
        DatosdelPacientePanel_2         matlab.ui.container.Panel
        CURPLabel                       matlab.ui.control.Label
        FechadenacimientoLabel          matlab.ui.control.Label
        SexoLabel                       matlab.ui.control.Label
        EdadLabel                       matlab.ui.control.Label
        NombreLabel                     matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        NuevoPacienteButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Folio
        a %Guarda datos checkbox con pregunta
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function result = Datos_generales_completos(app)
            d1 = isempty(app.NombresEditField_3.Value); d2 = isempty(app.ApellidoPaternoEditField.Value); d3 = isempty(app.NumeroexteriorEditField.Value);
            d4 = isempty(app.NumerointeriorEditField.Value);d5 = isempty(app.CalleEditField.Value);d6 = isempty(app.ColoniaEditField.Value);
            d7 = isempty(app.CodigopostalEditField.Value);d8 = isempty(app.MunicipiooalcaldiaEditField.Value);d9 = isempty(app.ApellidoMaternoEditField.Value);
            d10 = strcmp(app.SexoDropDown.Value,'Seleccionar');d11 = strcmp(app.LugardeNacimientoDropDown,'Seleccionar');d13 = isnat(app.FechadenacimientoDatePicker.Value);
            d12 = strcmp(app.EntidadFederativaDropDown.Value,'Seleccionar');
            if ~(d1==1 || d2==1 || d3==1 || d4==1 || d5==1 || d6==1 || d7==1 || d8==1 || d9==1 || d10==1 || d11==1 || d12==1 || d13 == 1)
                result = 'incompleto';
            else
                result = 'completo';
            end
        end
        
        function result = Historial_clinico_completo(app)
            d(1)= ~isempty(app.QuinEditField.Value);d(2) = ~isempty(app.QuinEditField_2.Value);d(3) = ~isempty(app.QuinEditField_3.Value);
            d(4)= ~isempty(app.QuinEditField_4.Value);d(5) = ~isempty(app.QuinEditField_5.Value);d(6) = ~isempty(app.QuinEditField_6.Value);
            d(7)= ~isempty(app.QuinEditField_7.Value);d(8)= ~isempty(app.TipoEditField.Value);
            %
            d(9)= ~isempty(app.CulEditField_3.Value);d(10)= ~isempty(app.CulEditField_4.Value);
            %
            d11 = strcmp(app.NivelactivafsicaDropDown.Value,'Seleccionar');d12 = strcmp(app.AlimentacinDropDown.Value,'Seleccionar');
            %
            d13 = isempty(app.TratamientosPreviosEditField.Value);
            d14 = isempty(app.PadecimientoactualEditField.Value);

            d15 = isempty(app.GrupoetnicoEditField.Value);
            d16 = isempty(app.TipodesangreEditField_3.Value);

            for i=1:1:10
                if ~((app.a(i) ~= d(i)) || d11 == 1 || d12 == 1 || d13==1 || d14==1 || d15==1 || d16==1)
                    result = 'incompleto';
                    break;
                end
                result = 'completo';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Folio = 1;
            app.FolioEditField.Value = app.Folio;
            %app.FotografaTab.
            %app.GraphECG.Visible = 'off';
            app.a = zeros(1,10);
        end

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
                % Esta funcion ya no se ocupa pero estan las graficas

                %%ECG 
                app.GraphECG.Visible = 'on';

                ECG = [109,109,109,109,109,109,110,110,110,110,111,112,114,116,120,123,128,132,136,140,145,149,153,158,162,165,167,168,167,164];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
                ECG = ECG * 1.5e-3 / 255;
                fs = 200;
                ts = 1/fs;
                tf = 200*ts % tiempo final = Datos totales * ts
                t = 0:ts:tf-ts;
            
                plot(app.GraphECG,t,ECG,'LineWidth',2,'Color',[1,0.1,0.2])
                axis(app.GraphECG,[0,tf,0,1.8e-3])
                
                
                %%Saturacion de oxigeno
                app.GraphSaturacionOxigeno.Visible = 'on';

                sat1 = app.Dato1EditField.Value;
                sat2 = app.Dato2EditField.Value;
                sat3 = app.Dato3EditField.Value;
                sat4 = app.Dato4EditField.Value;
                sat5 = app.Dato5EditField.Value;
                sat6 = app.Dato6EditField.Value;
                sat7 = app.Dato7EditField.Value;
                sat8 = app.Dato8EditField.Value;
                sat9 = app.Dato9EditField.Value;
                sat10 = app.Dato10EditField.Value;

                Vsat = [sat1, sat2, sat3, sat4, sat5, sat6, sat7, sat8, sat9,sat10];
                    
                Presion = 10:10:100;

                plot(app.GraphSaturacionOxigeno,Presion,Vsat,'LineWidth',2,'Color',[0.1,0.2,1])
                axis(app.GraphSaturacionOxigeno,[0,100,0,100])

                %%IMC
                P = app.PesoKgEditField.Value;
                A = app.TallamEditField.Value;
                IMC = P/A^2;
                app.IMCEditField.Value = IMC;
                
        end

        % Menu selected function: ClaroMenu
        function ClaroMenuSelected(app, event)
            %NO VA
            %Changecolor_White(app);
            %Fondo
            app.DatosgeneralesTab.BackgroundColor = [0.94,0.94,0.94];
            % Textos
            app.SexoMoFEditField.BackgroundColor = [0 0 0];
            app.datos = [0 0 0];
            app.CargarDatosButton = [0 0 0];
            app.Validar = [0 0 0];
            app.NumeroexteriorEditField = [0 0 0];
            app.NumerointeriorEditField = [0 0 0];
            app.CalleEditField = [0 0 0];
            app.ColoniaEditField = [0 0 0];
            app.CodigopostalEditField = [0 0 0];
            app.MunicipiooalcaldiaEditField = [0 0 0];
            app.EntidadfederativaEditField = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            % app. = [0 0 0];
            
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            global stop
            global v
            stop = 0;
            v = videoinput("winvideo", 1, "MJPG_1280x720");
            v.ReturnedColorspace = "rgb";
            hImage = image(app.UIAxes_Preview,zeros(1280,720,3));
            app.UIAxes_Preview.XLim = [0 1280];
            app.UIAxes_Preview.YLim = [0 720];
            app.UIAxes_Preview.XTick = [];
            app.UIAxes_Preview.YTick = [];
            pbaspect(app.UIAxes_Preview,[1280,720,1]);
            preview(v,hImage)
            while(stop ~= 1)
                pause(0.1)
            end
        end

        % Button pushed function: TomarfotoButton
        function TomarfotoButtonPushed(app, event)
            global v
            global stop
            delete(v)
            v = videoinput("winvideo", 1, "MJPG_1280x720");
            v.ReturnedColorspace = "rgb";
            hImage2 = image(app.UIAxesFoto,zeros(1280,720,3));
            snapshot2 = getsnapshot(v);
            app.UIAxesFoto.XLim = [0 1280];
            app.UIAxesFoto.YLim = [0 720];
            app.UIAxesFoto.XTick = [];
            app.UIAxesFoto.YTick = [];
            pbaspect(app.UIAxesFoto,[1280,720,1])
            stop = 1;
            
            imwrite(snapshot2,'fotografia.png')
            imgpaciente = imread('fotografia.png');
            detectar_rostro = vision.CascadeObjectDetector('ScaleFactor', 1.2, 'MinSize', [30 30], 'MergeThreshold', 5);
            
            cara = step(detectar_rostro, imgpaciente);
            
            if isempty(cara)
                errordlg('Rostro no detectado ');
                imshow(snapshot2,'Parent',app.UIAxesFoto);
            elseif size(cara, 1) > 1
                errordlg('Más de un rostro fue detectado ');
                imshow(snapshot2,'Parent',app.UIAxesFoto);
            else
               imshow(snapshot2,'Parent',app.UIAxesFoto);
            end
        end

        % Button pushed function: IrafotografiaButton
        function IrafotografiaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FotografaTab;
            Datos_generales_completos(app);
            
        end

        % Button down function: HistoriaClnicaTab
        function HistoriaClnicaTabButtonDown(app, event)
            result = Datos_generales_completos(app);
            if strcmp(result,'incompleto')
                errordlg('Finaliza la sección datos generales ')
                app.TabGroup.SelectedTab = app.DatosgeneralesTab;
            end
            
        end

        % Value changed function: EnfermedadescardiacasCheckBox
        function EnfermedadescardiacasCheckBoxValueChanged(app, event)
            value = app.EnfermedadescardiacasCheckBox.Value;
            if value == 1
                app.QuinEditField.Enable = 'on';
                app.QuinEditFieldLabel.Enable = 'on';
                app.a(1) = 1;
            else
                app.QuinEditField.Enable = 'off';
                app.QuinEditFieldLabel.Enable = 'off';
                app.a(1) = 0;
            end
        end

        % Value changed function: HipertensinCheckBox
        function HipertensinCheckBoxValueChanged(app, event)
            value = app.HipertensinCheckBox.Value;
            if value == 1
                app.QuinEditField_2.Enable = 'on';
                app.QuinEditField_2Label.Enable = 'on';
                app.a(2) = 1;
            else
                app.QuinEditField_2.Enable = 'off';
                app.QuinEditField_2Label.Enable = 'off';
                app.a(2) = 0;
            end
        end

        % Value changed function: DiabetestipoICheckBox
        function DiabetestipoICheckBoxValueChanged(app, event)
            value = app.DiabetestipoICheckBox.Value;
            if value == 1
                app.QuinEditField_3.Enable = 'on';
                app.QuinEditField_3Label.Enable = 'on';
                app.a(3) = 1;
            else
                app.QuinEditField_3.Enable = 'off';
                app.QuinEditField_3Label.Enable = 'off';
                app.a(3) = 0;
            end
        
        end

        % Value changed function: DiabetestipoIICheckBox
        function DiabetestipoIICheckBoxValueChanged(app, event)
            value = app.DiabetestipoIICheckBox.Value;
            
            if value == 1
                app.QuinEditField_4.Enable = 'on';
                app.QuinEditField_4Label.Enable = 'on';
                app.a(4) = 1;
            else
                app.QuinEditField_4.Enable = 'off';
                app.QuinEditField_4Label.Enable = 'off';
                app.a(4) = 0;
            end
        end

        % Value changed function: ParkinsonCheckBox
        function ParkinsonCheckBoxValueChanged(app, event)
            value = app.ParkinsonCheckBox.Value;
            
            if value == 1
                app.QuinEditField_5.Enable = 'on';
                app.QuinEditField_5Label.Enable = 'on';
                app.a(5) = 1;
            else
                app.QuinEditField_5.Enable = 'off';
                app.QuinEditField_5Label.Enable = 'off';
                app.a(5) = 1;
            end
        end

        % Value changed function: FibrosisquisticaCheckBox
        function FibrosisquisticaCheckBoxValueChanged(app, event)
            value = app.FibrosisquisticaCheckBox.Value;
            if value == 1
                app.QuinEditField_6.Enable = 'on';
                app.QuinEditField_6Label.Enable = 'on';
                app.a(6) = 1;
            else
                app.QuinEditField_6.Enable = 'off';
                app.QuinEditField_6Label.Enable = 'off';
                app.a(6) = 0;
            end
        end

        % Value changed function: CancerCheckBox
        function CancerCheckBoxValueChanged(app, event)
            value = app.CancerCheckBox.Value;
            if value == 1
                app.QuinEditField_7.Enable = 'on';
                app.QuinEditField_7Label.Enable = 'on';
                app.TipoEditField.Enable = 'on';
                app.TipoEditFieldLabel.Enable = 'on';
                app.a(7) = 1;
                app.a(8) = 1;
            else
                app.QuinEditField_7.Enable = 'off';
                app.QuinEditField_7Label.Enable = 'off';
                app.TipoEditField.Enable = 'off';
                app.TipoEditFieldLabel.Enable = 'off';
                app.a(7) = 0;
                app.a(8) = 0;
            end
        end

        % Button down function: ExploracinFisicaTab
        function ExploracinFisicaTabButtonDown(app, event)
            value = app.Validardatos.Value;
            if strcmp(value,'Off')
                % Datos generales
                result = Datos_generales_completos(app);
                result2 = Historial_clinico_completo(app);
                if strcmp(result,'incompleto')
                    errordlg('Finaliza la sección datos generales ')
                    app.TabGroup.SelectedTab = app.DatosgeneralesTab;

                % Historial clinico
                
                elseif strcmp(result2,'incompleto')
                    errordlg('Finaliza historial clinico ')
                    app.TabGroup.SelectedTab = app.HistoriaClnicaTab;
                    
                %Todo completo    
                else
                    errordlg('Todo lleno ')
                    app.Validardatos.Enable = 'on';
                end 
            end
            
            
        end

        % Value changed function: CirugiasCheckBox_2
        function CirugiasCheckBox_2ValueChanged(app, event)
            value = app.CirugiasCheckBox_2.Value;
            if value == 1
                app.CulEditField_3.Enable = 'on';
                app.CulEditField_3Label.Enable = 'on';
                app.a(9) = 1;
            else
                app.CulEditField_3.Enable = 'off';
                app.CulEditField_3Label.Enable = 'off';
                app.a(9) = 0;
            end
        end

        % Value changed function: TransplantesCheckBox_2
        function TransplantesCheckBox_2ValueChanged(app, event)
            value = app.TransplantesCheckBox_2.Value;
            if value == 1
                app.CulEditField_4.Enable = 'on';
                app.CulEditField_4Label.Enable = 'on';
                app.a(10) = 1;
            else
                app.CulEditField_4.Enable = 'off';
                app.CulEditField_4Label.Enable = 'off';
                app.a(10) = 0;
            end
        end

        % Button pushed function: GuardarfotoButton
        function GuardarfotoButtonPushed(app, event)
            % Codigo para redimensionar foto y mostrarla
            imgpaciente = imread('fotografia.png'); 
            detector = vision.CascadeObjectDetector(); %Crear un objeto detector de caras
                
            bbox = step(detector, imgpaciente); % Detectar localización de la cara en la imagen
            
            % Obtener las coordenadas del primer rostro detectado
            x = bbox(1, 1);
            y = bbox(1, 2);
            width = bbox(1, 3);
            height = bbox(1, 4);
        
            % Extraer el rostro de la imagen original
            %rostro1 = imcrop(im, [x, y, width, height]);
            paciente = imcrop(imgpaciente, [x-200, y-200, width+400, height+400]);
        
            % Mostrar la imagen del rostro en interfaz
            app.Image.ImageSource = paciente;


            % Codigo para guardar foto


        end

        % Button pushed function: TomarSignosVitalesButton
        function TomarSignosVitalesButtonPushed(app, event)
            delete(instrfindall);
            obj1 = instrfind('Type','serial','BaudRate',9600,'Port','COM6');
            % 
            if isempty(obj1)
                obj1 = serial('COM6');
            else
                fclose(obj1);
                obj1 = obj1(1);
            end
            TD = 1;
            %Comenzar comunicación
            fopen(obj1);
            dataECG = 0;
            datatemp = 0
            %figure(1)
            i=1;
            app.GraphECG.XLim = [0,4];
            app.GraphECG.YLim = [0, 255];

            app.GraphTemperatura.XLim = [0,5];
            app.GraphTemperatura.YLim = [0, 500];
            while(TD == 1)
                tic
                data = fscanf(obj1);
                
                if str2num(data) == 2000 % Interrumpir Serial
                    TD = 9;
                    break
                else
                    %Extaer datos de la Trama
                    
                    aa=strfind(data,'A');
                    b=strfind(data,'T');
                    c=strfind(data,'P');
                    d=strfind(data,'C');
                    
                    ECG = str2num(data(aa+1:b-1));
                    Temp = str2num(data(b+1:c-1));
                    Pulso = str2num(data(c+1:d-1));
                    
                    %ECG
                    dataECG = cat(2,dataECG,ECG);
                    
                    if length(dataECG) > 200
                        grafica = dataECG(i-199:i);
                        t = (2*i/100)-(399/100):2/100:2*i/100;
                        plot(app.GraphECG,t,grafica);
                        app.GraphECG.XLim = [2*i/100-(399/100),2*i/100];
                        app.GraphECG.YLim = [0, 255];
                        %axis([2*i/100-(399/100),2*i/100,0,255])
                    else
                        t = 0:2/100:2*i/100;
                        plot(app.GraphECG,t,dataECG);
                        
                        %axis([0,4,0,255])
                    end

                    %Temperatura
                    datatemp = cat(2,datatemp,Temp);

                    tT = 0:2/100:2*i/100;
                    plot(app.GraphTemperatura,tT,datatemp);


                    pause(1/1000);
                    i=i+1;
                    time = toc
                end
            end
            errordlg('fin');
            fclose(obj1);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Expediente = uifigure('Visible', 'off');
            app.Expediente.Name = 'MATLAB App';
            app.InicioMenu = uimenu(app.Expediente);
            app.InicioMenu.Text = 'Inicio';
            app.CerrarsesinMenu = uimenu(app.InicioMenu);
            app.CerrarsesinMenu.Text = 'Cerrar sesión';
            app.VisualizacinMenu = uimenu(app.Expediente);
            app.VisualizacinMenu.Text = 'Visualización';
            app.ClaroMenu = uimenu(app.VisualizacinMenu);
            app.ClaroMenu.MenuSelectedFcn = createCallbackFcn(app, @ClaroMenuSelected, true);
            app.ClaroMenu.Text = 'Claro';
            app.OscuroMenu = uimenu(app.VisualizacinMenu);
            app.OscuroMenu.Text = 'Oscuro';
            app.TabGroup = uitabgroup(app.Expediente);
            app.DatosgeneralesTab = uitab(app.TabGroup);
            app.DatosgeneralesTab.Title = 'Datos generales';
            app.Panel_2 = uipanel(app.DatosgeneralesTab);
            app.NombresLabel = uilabel(app.Panel_2);
            app.NombresLabel.Text = 'Nombre(s):';
            app.NombresEditField_3 = uieditfield(app.Panel_2, 'text');
            app.ApellidoPaternoLabel = uilabel(app.Panel_2);
            app.ApellidoPaternoLabel.Text = 'Apellido Paterno:';
            app.ApellidoPaternoEditField = uieditfield(app.Panel_2, 'text');
            app.EdadEditField_3Label = uilabel(app.Panel_2);
            app.EdadEditField_3Label.Text = 'Edad';
            app.EdadEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.Panel_2);
            app.MunicipiooalcaldiaEditFieldLabel = uilabel(app.Panel_3);
            app.MunicipiooalcaldiaEditFieldLabel.Text = 'Municipio o alcaldia';
            app.MunicipiooalcaldiaEditField = uieditfield(app.Panel_3, 'text');
            app.ColoniaEditFieldLabel = uilabel(app.Panel_3);
            app.ColoniaEditFieldLabel.Text = 'Colonia';
            app.ColoniaEditField = uieditfield(app.Panel_3, 'text');
            app.CalleEditFieldLabel = uilabel(app.Panel_3);
            app.CalleEditFieldLabel.Text = 'Calle';
            app.CalleEditField = uieditfield(app.Panel_3, 'text');
            app.NumerointeriorEditFieldLabel = uilabel(app.Panel_3);
            app.NumerointeriorEditFieldLabel.Text = 'Numero interior';
            app.NumerointeriorEditField = uieditfield(app.Panel_3, 'text');
            app.NumeroexteriorEditFieldLabel = uilabel(app.Panel_3);
            app.NumeroexteriorEditFieldLabel.Text = 'Numero exterior';
            app.NumeroexteriorEditField = uieditfield(app.Panel_3, 'text');
            app.EntidadFederativaDropDownLabel = uilabel(app.Panel_3);
            app.EntidadFederativaDropDownLabel.Text = 'Entidad Federativa';
            app.EntidadFederativaDropDown = uidropdown(app.Panel_3);
            app.EntidadFederativaDropDown.Items = {'Seleccionar', 'Aguascalientes', 'Baja California', 'Baja California Sur', 'Campeche', 'Chiapas', 'Chihuahua', 'Coahuila de Zaragoza', 'Colima', 'Ciudad de México', 'Durango', 'Guanajuato', 'Guerrero', 'Hidalgo', 'Jalisco', 'México', 'Michoacán de Ocampo', 'Morelos', 'Nayarit', 'Nuevo León', 'Oaxaca', 'Puebla', 'Querétaro', 'Quintana Roo', 'San Luis Potosí', 'Sinaloa', 'Sonora', 'Tabasco', 'Tamaulipas', 'Tlaxcala', 'Veracruz de Ignacio de la Llave', 'Yucatán', 'Zacatecas'};
            app.EntidadFederativaDropDown.Value = 'Seleccionar';
            app.CodigopostalEditField_2Label = uilabel(app.Panel_3);
            app.CodigopostalEditField_2Label.Text = 'Codigo postal';
            app.CodigopostalEditField = uieditfield(app.Panel_3, 'text');
            app.ApellidoMaternoLabel = uilabel(app.Panel_2);
            app.ApellidoMaternoLabel.Text = 'Apellido Materno:';
            app.ApellidoMaternoEditField = uieditfield(app.Panel_2, 'text');
            app.Image3 = uiimage(app.Panel_2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'usuario.png');
            app.InformacindelpacienteLabel = uilabel(app.Panel_2);
            app.InformacindelpacienteLabel.Text = 'Información del paciente';
            app.DomicilioLabel = uilabel(app.Panel_2);
            app.DomicilioLabel.Text = 'Domicilio';
            app.Image3_2 = uiimage(app.Panel_2);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'casa.png');
            app.FechadenacimientoDatePickerLabel = uilabel(app.Panel_2);
            app.FechadenacimientoDatePickerLabel.Text = 'Fecha de nacimiento';
            app.FechadenacimientoDatePicker = uidatepicker(app.Panel_2);
            app.CURPEditFieldLabel = uilabel(app.Panel_2);
            app.CURPEditFieldLabel.Text = 'CURP';
            app.CURPEditField = uieditfield(app.Panel_2, 'text');
            app.LugardeNacimientoDropDownLabel = uilabel(app.Panel_2);
            app.LugardeNacimientoDropDownLabel.Text = 'Lugar de Nacimiento';
            app.LugardeNacimientoDropDown = uidropdown(app.Panel_2);
            app.LugardeNacimientoDropDown.Items = {'Seleccionar', 'Aguascalientes', 'Baja California', 'Baja California Sur', 'Campeche', 'Chiapas', 'Chihuahua', 'Coahuila de Zaragoza', 'Colima', 'Ciudad de México', 'Durango', 'Guanajuato', 'Guerrero', 'Hidalgo', 'Jalisco', 'México', 'Michoacán de Ocampo', 'Morelos', 'Nayarit', 'Nuevo León', 'Oaxaca', 'Puebla', 'Querétaro', 'Quintana Roo', 'San Luis Potosí', 'Sinaloa', 'Sonora', 'Tabasco', 'Tamaulipas', 'Tlaxcala', 'Veracruz de Ignacio de la Llave', 'Yucatán', 'Zacatecas'};
            app.LugardeNacimientoDropDown.Value = 'Seleccionar';
            app.SexoDropDownLabel = uilabel(app.Panel_2);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.Panel_2);
            app.SexoDropDown.Items = {'Seleccionar', 'Hombre', 'Mujer', 'Otro'};
            app.SexoDropDown.Value = 'Seleccionar';
            app.CargarDatosButton = uibutton(app.Panel_2, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.GenerarCURPButton = uibutton(app.Panel_2, 'push');
            app.GenerarCURPButton.Text = 'Generar CURP';
            app.HistoriaClnicaTab = uitab(app.TabGroup);
            app.HistoriaClnicaTab.Title = 'Historia Clínica';
            app.HistoriaClnicaTab.ButtonDownFcn = createCallbackFcn(app, @HistoriaClnicaTabButtonDown, true);
            app.Panel_5 = uipanel(app.HistoriaClnicaTab);
            app.EnfermedadescardiacasCheckBox = uicheckbox(app.Panel_5);
            app.EnfermedadescardiacasCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnfermedadescardiacasCheckBoxValueChanged, true);
            app.EnfermedadescardiacasCheckBox.Text = 'Enfermedades cardiacas';
            app.HipertensinCheckBox = uicheckbox(app.Panel_5);
            app.HipertensinCheckBox.ValueChangedFcn = createCallbackFcn(app, @HipertensinCheckBoxValueChanged, true);
            app.HipertensinCheckBox.Text = 'Hipertensión';
            app.DiabetestipoICheckBox = uicheckbox(app.Panel_5);
            app.DiabetestipoICheckBox.ValueChangedFcn = createCallbackFcn(app, @DiabetestipoICheckBoxValueChanged, true);
            app.DiabetestipoICheckBox.Text = 'Diabetes tipo I';
            app.DiabetestipoIICheckBox = uicheckbox(app.Panel_5);
            app.DiabetestipoIICheckBox.ValueChangedFcn = createCallbackFcn(app, @DiabetestipoIICheckBoxValueChanged, true);
            app.DiabetestipoIICheckBox.Text = 'Diabetes tipo II';
            app.CancerCheckBox = uicheckbox(app.Panel_5);
            app.CancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @CancerCheckBoxValueChanged, true);
            app.CancerCheckBox.Text = 'Cancer';
            app.ParkinsonCheckBox = uicheckbox(app.Panel_5);
            app.ParkinsonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParkinsonCheckBoxValueChanged, true);
            app.ParkinsonCheckBox.Text = 'Parkinson';
            app.FibrosisquisticaCheckBox = uicheckbox(app.Panel_5);
            app.FibrosisquisticaCheckBox.ValueChangedFcn = createCallbackFcn(app, @FibrosisquisticaCheckBoxValueChanged, true);
            app.FibrosisquisticaCheckBox.Text = 'Fibrosis quistica';
            app.TipoEditFieldLabel = uilabel(app.Panel_5);
            app.TipoEditFieldLabel.Enable = 'off';
            app.TipoEditFieldLabel.Text = 'Tipo';
            app.TipoEditField = uieditfield(app.Panel_5, 'text');
            app.TipoEditField.Enable = 'off';
            app.OtroEditField_5Label = uilabel(app.Panel_5);
            app.OtroEditField_5Label.Text = 'Otro';
            app.OtroEditField_5 = uieditfield(app.Panel_5, 'text');
            app.OtroEditField_5.Placeholder = 'Especifique';
            app.QuinEditFieldLabel = uilabel(app.Panel_5);
            app.QuinEditFieldLabel.Enable = 'off';
            app.QuinEditFieldLabel.Text = '¿Quién?';
            app.QuinEditField = uieditfield(app.Panel_5, 'text');
            app.QuinEditField.Enable = 'off';
            app.QuinEditField_2Label = uilabel(app.Panel_5);
            app.QuinEditField_2Label.Enable = 'off';
            app.QuinEditField_2Label.Text = '¿Quién?';
            app.QuinEditField_2 = uieditfield(app.Panel_5, 'text');
            app.QuinEditField_2.Enable = 'off';
            app.QuinEditField_3Label = uilabel(app.Panel_5);
            app.QuinEditField_3Label.Enable = 'off';
            app.QuinEditField_3Label.Text = '¿Quién?';
            app.QuinEditField_3 = uieditfield(app.Panel_5, 'text');
            app.QuinEditField_3.Enable = 'off';
            app.QuinEditField_4Label = uilabel(app.Panel_5);
            app.QuinEditField_4Label.Enable = 'off';
            app.QuinEditField_4Label.Text = '¿Quién?';
            app.QuinEditField_4 = uieditfield(app.Panel_5, 'text');
            app.QuinEditField_4.Enable = 'off';
            app.QuinEditField_5Label = uilabel(app.Panel_5);
            app.QuinEditField_5Label.Enable = 'off';
            app.QuinEditField_5Label.Text = '¿Quién?';
            app.QuinEditField_5 = uieditfield(app.Panel_5, 'text');
            app.QuinEditField_5.Enable = 'off';
            app.QuinEditField_6Label = uilabel(app.Panel_5);
            app.QuinEditField_6Label.Enable = 'off';
            app.QuinEditField_6Label.Text = '¿Quién?';
            app.QuinEditField_6 = uieditfield(app.Panel_5, 'text');
            app.QuinEditField_6.Enable = 'off';
            app.QuinEditField_7Label = uilabel(app.Panel_5);
            app.QuinEditField_7Label.Enable = 'off';
            app.QuinEditField_7Label.Text = '¿Quién?';
            app.QuinEditField_7 = uieditfield(app.Panel_5, 'text');
            app.QuinEditField_7.Enable = 'off';
            app.AntecendetesheredofamiliaresLabel = uilabel(app.HistoriaClnicaTab);
            app.AntecendetesheredofamiliaresLabel.Text = 'Antecendetes heredofamiliares';
            app.Panel_6 = uipanel(app.HistoriaClnicaTab);
            app.Panel_7 = uipanel(app.Panel_6);
            app.AlcoholCheckBox = uicheckbox(app.Panel_7);
            app.AlcoholCheckBox.Text = 'Alcohol';
            app.TabacoCheckBox = uicheckbox(app.Panel_7);
            app.TabacoCheckBox.Text = 'Tabaco';
            app.SustanciaspsicoactivasCheckBox = uicheckbox(app.Panel_7);
            app.SustanciaspsicoactivasCheckBox.Text = 'Sustancias psicoactivas';
            app.NivelactivafsicaDropDownLabel = uilabel(app.Panel_7);
            app.NivelactivafsicaDropDownLabel.Text = 'Nivel activa física';
            app.NivelactivafsicaDropDown = uidropdown(app.Panel_7);
            app.NivelactivafsicaDropDown.Items = {'Seleccionar', 'Nula', 'Baja', 'Media', 'Alta'};
            app.NivelactivafsicaDropDown.Value = 'Seleccionar';
            app.AlimentacinDropDownLabel = uilabel(app.Panel_7);
            app.AlimentacinDropDownLabel.Text = 'Alimentación';
            app.AlimentacinDropDown = uidropdown(app.Panel_7);
            app.AlimentacinDropDown.Items = {'Seleccionar', 'Saludable', 'No saludable', 'Vegetariana', 'Vegana', 'Dieta especifica'};
            app.AlimentacinDropDown.Value = 'Seleccionar';
            app.AntecendetespersonalespatologicosLabel = uilabel(app.HistoriaClnicaTab);
            app.AntecendetespersonalespatologicosLabel.Text = 'Antecendetes personales patologicos';
            app.AntecendetespersonalesnopatologicosLabel = uilabel(app.HistoriaClnicaTab);
            app.AntecendetespersonalesnopatologicosLabel.Text = 'Antecendetes personales no patologicos';
            app.GrupoetnicoEditFieldLabel = uilabel(app.HistoriaClnicaTab);
            app.GrupoetnicoEditFieldLabel.Text = 'Grupo etnico';
            app.GrupoetnicoEditField = uieditfield(app.HistoriaClnicaTab, 'text');
            app.Panel_8 = uipanel(app.HistoriaClnicaTab);
            app.Panel_9 = uipanel(app.Panel_8);
            app.DiabetestipoICheckBox_4 = uicheckbox(app.Panel_9);
            app.DiabetestipoICheckBox_4.Text = 'Diabetes tipo I';
            app.DiabetestipoIICheckBox_3 = uicheckbox(app.Panel_9);
            app.DiabetestipoIICheckBox_3.Text = 'Diabetes tipo II';
            app.HipertensinCheckBox_3 = uicheckbox(app.Panel_9);
            app.HipertensinCheckBox_3.Text = 'Hipertensión';
            app.EnfermedadescardiacasCheckBox_2 = uicheckbox(app.Panel_9);
            app.EnfermedadescardiacasCheckBox_2.Text = 'Enfermedades cardiacas';
            app.TranstornosendocrinosCheckBox_2 = uicheckbox(app.Panel_9);
            app.TranstornosendocrinosCheckBox_2.Text = 'Transtornos endocrinos';
            app.VIHCheckBox_2 = uicheckbox(app.Panel_9);
            app.VIHCheckBox_2.Text = 'VIH';
            app.CirugiasCheckBox_2 = uicheckbox(app.Panel_9);
            app.CirugiasCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CirugiasCheckBox_2ValueChanged, true);
            app.CirugiasCheckBox_2.Text = 'Cirugias';
            app.TransplantesCheckBox_2 = uicheckbox(app.Panel_9);
            app.TransplantesCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @TransplantesCheckBox_2ValueChanged, true);
            app.TransplantesCheckBox_2.Text = 'Transplantes';
            app.LupusCheckBox_2 = uicheckbox(app.Panel_9);
            app.LupusCheckBox_2.Text = 'Lupus';
            app.CulEditField_3Label = uilabel(app.Panel_9);
            app.CulEditField_3Label.Enable = 'off';
            app.CulEditField_3Label.Text = '¿Cuál?';
            app.CulEditField_3 = uieditfield(app.Panel_9, 'text');
            app.CulEditField_3.Enable = 'off';
            app.CulEditField_4Label = uilabel(app.Panel_9);
            app.CulEditField_4Label.Enable = 'off';
            app.CulEditField_4Label.Text = '¿Cuál?';
            app.CulEditField_4 = uieditfield(app.Panel_9, 'text');
            app.CulEditField_4.Enable = 'off';
            app.OtroEditField_4Label = uilabel(app.Panel_9);
            app.OtroEditField_4Label.Text = 'Otro';
            app.OtroEditField_4 = uieditfield(app.Panel_9, 'text');
            app.TipodesangreEditField_3Label = uilabel(app.HistoriaClnicaTab);
            app.TipodesangreEditField_3Label.Text = 'Tipo de sangre';
            app.TipodesangreEditField_3 = uieditfield(app.HistoriaClnicaTab, 'text');
            app.TratamientosPreviosEditFieldLabel = uilabel(app.HistoriaClnicaTab);
            app.TratamientosPreviosEditFieldLabel.Text = 'Tratamientos Previos';
            app.TratamientosPreviosEditField = uieditfield(app.HistoriaClnicaTab, 'text');
            app.PadecimientoactualEditFieldLabel = uilabel(app.HistoriaClnicaTab);
            app.PadecimientoactualEditFieldLabel.Text = 'Padecimiento actual';
            app.PadecimientoactualEditField = uieditfield(app.HistoriaClnicaTab, 'text');
            app.ExploracinFisicaTab = uitab(app.TabGroup);
            app.ExploracinFisicaTab.Title = 'Exploración Fisica';
            app.ExploracinFisicaTab.ButtonDownFcn = createCallbackFcn(app, @ExploracinFisicaTabButtonDown, true);
            app.Panel_10 = uipanel(app.ExploracinFisicaTab);
            app.Panel3 = uipanel(app.Panel_10);
            app.Panel3.Title = 'Panel3';
            app.ELECTROCARDIOGRAMAPanel = uipanel(app.Panel3);
            app.ELECTROCARDIOGRAMAPanel.Title = 'ELECTROCARDIOGRAMA';
            app.GraphECG = uiaxes(app.ELECTROCARDIOGRAMAPanel);
            title(app.GraphECG, 'ECG Paciente')
            xlabel(app.GraphECG, 'Tiempo (s)')
            ylabel(app.GraphECG, 'Amplitud (mV)')
            app.GraphECG.XLim = [0 5];
            app.GraphECG.YLim = [0 5];
            app.GraphECG.ZLim = [0 5];
            app.GraphECG.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49];
            app.GraphECG.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'};
            app.SATURACIONDEOXIGENOPanel = uipanel(app.Panel3);
            app.SATURACIONDEOXIGENOPanel.Title = 'SATURACION DE OXIGENO';
            app.GraphSaturacionOxigeno = uiaxes(app.SATURACIONDEOXIGENOPanel);
            title(app.GraphSaturacionOxigeno, 'Saturación de Oxigeno')
            xlabel(app.GraphSaturacionOxigeno, 'PO2 mmHg')
            ylabel(app.GraphSaturacionOxigeno, '% Saturación (SO2)')
            zlabel(app.GraphSaturacionOxigeno, 'Z')
            app.GraphSaturacionOxigeno.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.GraphSaturacionOxigeno.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.GraphSaturacionOxigeno.YTick = [0 20 40 60 80 100];
            app.GraphSaturacionOxigeno.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.GraphSaturacionOxigeno.Visible = 'off';
            app.Slider2Label = uilabel(app.Panel3);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.Panel3);
            app.SliderLabel = uilabel(app.Panel3);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel3);
            app.TemperaturaCEditFieldLabel = uilabel(app.Panel3);
            app.TemperaturaCEditFieldLabel.Text = 'Temperatura °C';
            app.TemperaturaCEditField = uieditfield(app.Panel3, 'numeric');
            app.TemperaturaPanel = uipanel(app.Panel3);
            app.TemperaturaPanel.Title = 'Temperatura';
            app.GraphTemperatura = uiaxes(app.TemperaturaPanel);
            title(app.GraphTemperatura, 'Saturación de Oxigeno')
            xlabel(app.GraphTemperatura, 'PO2 mmHg')
            ylabel(app.GraphTemperatura, '% Saturación (SO2)')
            zlabel(app.GraphTemperatura, 'Z')
            app.GraphTemperatura.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.GraphTemperatura.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'};
            app.GraphTemperatura.YTick = [0 20 40 60 80 100];
            app.GraphTemperatura.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.TensinArterialmmHgEditFieldLabel = uilabel(app.Panel3);
            app.TensinArterialmmHgEditFieldLabel.Text = 'Tensión Arterial (mmHg)';
            app.TensinArterialmmHgEditField = uieditfield(app.Panel3, 'text');
            app.FrecuenciacardiacalatminEditFieldLabel = uilabel(app.Panel3);
            app.FrecuenciacardiacalatminEditFieldLabel.Text = 'Frecuencia cardiaca (lat/min)';
            app.FrecuenciacardiacalatminEditField = uieditfield(app.Panel3, 'numeric');
            app.FrecuenciarespiratoriarespminEditFieldLabel = uilabel(app.Panel3);
            app.FrecuenciarespiratoriarespminEditFieldLabel.Text = 'Frecuencia respiratoria (resp/min)';
            app.FrecuenciarespiratoriarespminEditField = uieditfield(app.Panel3, 'numeric');
            app.SIGNOSVITALESLabel = uilabel(app.ExploracinFisicaTab);
            app.SIGNOSVITALESLabel.Text = 'SIGNOS VITALES';
            app.PesoKgEditFieldLabel = uilabel(app.ExploracinFisicaTab);
            app.PesoKgEditFieldLabel.Text = 'Peso (Kg)';
            app.PesoKgEditField = uieditfield(app.ExploracinFisicaTab, 'numeric');
            app.TallamEditFieldLabel = uilabel(app.ExploracinFisicaTab);
            app.TallamEditFieldLabel.Text = 'Talla (m)';
            app.TallamEditField = uieditfield(app.ExploracinFisicaTab, 'numeric');
            app.IMCEditField_2Label = uilabel(app.ExploracinFisicaTab);
            app.IMCEditField_2Label.Text = 'IMC';
            app.IMCEditField_2 = uieditfield(app.ExploracinFisicaTab, 'numeric');
            app.HabitusexteriorEditFieldLabel = uilabel(app.ExploracinFisicaTab);
            app.HabitusexteriorEditFieldLabel.Text = 'Habitus exterior';
            app.HabitusexteriorEditField = uieditfield(app.ExploracinFisicaTab, 'text');
            app.TomarSignosVitalesButton = uibutton(app.ExploracinFisicaTab, 'push');
            app.TomarSignosVitalesButton.ButtonPushedFcn = createCallbackFcn(app, @TomarSignosVitalesButtonPushed, true);
            app.TomarSignosVitalesButton.Text = 'Tomar Signos Vitales';
            app.FotografaTab = uitab(app.TabGroup);
            app.FotografaTab.Title = 'Fotografía';
            app.Panel = uipanel(app.FotografaTab);
            app.UIAxes_Preview = uiaxes(app.Panel);
            zlabel(app.UIAxes_Preview, 'Z')
            app.UIAxes_Preview.Visible = 'off';
            app.PreviewButton = uibutton(app.Panel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.TomarfotoButton = uibutton(app.Panel, 'push');
            app.TomarfotoButton.ButtonPushedFcn = createCallbackFcn(app, @TomarfotoButtonPushed, true);
            app.TomarfotoButton.Text = 'Tomar foto';
            app.Panel_11 = uipanel(app.FotografaTab);
            app.UIAxesFoto = uiaxes(app.Panel_11);
            xlabel(app.UIAxesFoto, 'X')
            ylabel(app.UIAxesFoto, 'Y')
            zlabel(app.UIAxesFoto, 'Z')
            app.GuardarfotoButton = uibutton(app.Panel_11, 'push');
            app.GuardarfotoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarfotoButtonPushed, true);
            app.GuardarfotoButton.Text = 'Guardar foto';
            app.FotografadelpacienteLabel = uilabel(app.FotografaTab);
            app.FotografadelpacienteLabel.Text = 'Fotografía del paciente';
            app.EditFieldLabel = uilabel(app.FotografaTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.FotografaTab, 'text');
            app.DatosFinalesTab = uitab(app.TabGroup);
            app.DatosFinalesTab.Title = 'Datos Finales';
            app.DatosdelPacientePanel = uipanel(app.DatosFinalesTab);
            app.DatosdelPacientePanel.Title = 'Datos del Paciente';
            app.NuevoPacienteButton = uibutton(app.DatosdelPacientePanel, 'push');
            app.NuevoPacienteButton.Text = 'Nuevo Paciente';
            app.Image4 = uiimage(app.DatosdelPacientePanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'body_man.png');
            app.Image_2 = uiimage(app.DatosdelPacientePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'usuario.png');
            app.DatosdelPacientePanel_2 = uipanel(app.DatosdelPacientePanel);
            app.DatosdelPacientePanel_2.Title = 'Datos del Paciente';
            app.NombreLabel = uilabel(app.DatosdelPacientePanel_2);
            app.NombreLabel.Text = 'Nombre';
            app.EdadLabel = uilabel(app.DatosdelPacientePanel_2);
            app.EdadLabel.Text = 'Edad';
            app.SexoLabel = uilabel(app.DatosdelPacientePanel_2);
            app.SexoLabel.Text = 'Sexo';
            app.FechadenacimientoLabel = uilabel(app.DatosdelPacientePanel_2);
            app.FechadenacimientoLabel.Text = 'Fecha de nacimiento';
            app.CURPLabel = uilabel(app.DatosdelPacientePanel_2);
            app.CURPLabel.Text = 'CURP';
            app.DOMICILIOLabel = uilabel(app.DatosdelPacientePanel);
            app.DOMICILIOLabel.Text = 'DOMICILIO';
            app.ANTECENDETESHEREDOFAMILIARESLabel = uilabel(app.DatosdelPacientePanel);
            app.ANTECENDETESHEREDOFAMILIARESLabel.Text = 'ANTECENDETES HEREDOFAMILIARES';
            app.ANTECENDETESPERSONALESPATOLOGICOSLabel = uilabel(app.DatosdelPacientePanel);
            app.ANTECENDETESPERSONALESPATOLOGICOSLabel.Text = 'ANTECENDETES PERSONALES PATOLOGICOS';
            app.ANTECENDETESPERSONALESNOPATOLOGICOSLabel = uilabel(app.DatosdelPacientePanel);
            app.ANTECENDETESPERSONALESNOPATOLOGICOSLabel.Text = 'ANTECENDETES PERSONALES NO PATOLOGICOS';
            app.Panel2 = uipanel(app.DatosdelPacientePanel);
            app.Panel2.Title = 'Panel2';
            app.FreccardLabel = uilabel(app.Panel2);
            app.FreccardLabel.Text = 'Frec. card';
            app.TemperaturaLabel = uilabel(app.Panel2);
            app.TemperaturaLabel.Text = 'Temperatura';
            app.FrecrespLabel = uilabel(app.Panel2);
            app.FrecrespLabel.Text = 'Frec. resp';
            app.Panel_14 = uipanel(app.Panel2);
            app.GraphECG_2 = uiaxes(app.Panel_14);
            xlabel(app.GraphECG_2, 'Tiempo (s)')
            ylabel(app.GraphECG_2, 'Amplitud (mV)')
            app.GraphECG_2.XLim = [0 5];
            app.GraphECG_2.YLim = [0 5];
            app.GraphECG_2.ZLim = [0 5];
            app.GraphECG_2.XTick = [0 0.25 0.5 0.75 1];
            app.GraphECG_2.Visible = 'off';
            app.FolioLabel = uilabel(app.DatosdelPacientePanel);
            app.FolioLabel.Text = 'Folio:';
            app.Image = uiimage(app.Expediente);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'usuario.png');
            app.EXPEDIENTECLNICOELECTRNICOLabel = uilabel(app.Expediente);
            app.EXPEDIENTECLNICOELECTRNICOLabel.Text = 'EXPEDIENTE CLÍNICO ELECTRÓNICO';
            app.Image2 = uiimage(app.Expediente);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Jirafa1.gif');
            app.IrafotografiaButton = uibutton(app.Expediente, 'push');
            app.IrafotografiaButton.ButtonPushedFcn = createCallbackFcn(app, @IrafotografiaButtonPushed, true);
            app.IrafotografiaButton.Text = 'Ir a fotografia';
            app.FolioEditFieldLabel = uilabel(app.Expediente);
            app.FolioEditFieldLabel.Visible = 'off';
            app.FolioEditFieldLabel.Text = 'Folio';
            app.FolioEditField = uieditfield(app.Expediente, 'numeric');
            app.FolioEditField.Visible = 'off';
            app.Panel_12 = uipanel(app.Expediente);
            app.SoncorrectortusdatosSwitchLabel = uilabel(app.Panel_12);
            app.SoncorrectortusdatosSwitchLabel.Enable = 'off';
            app.SoncorrectortusdatosSwitchLabel.Text = '¿Son corrector tus datos?';
            app.Validardatos = uiswitch(app.Panel_12, 'slider');
            app.Validardatos.Enable = 'off';
            app.Lampconfirmacion = uilamp(app.Panel_12);
            % Show the figure after all components are created
            app.Expediente.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Expediente_Clinico_2do

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Expediente)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Expediente)
        end
    end
end