classdef Log_in < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Login                  matlab.ui.Figure
        Label                  matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        MostrarcontraseaLabel  matlab.ui.control.Label
        Mostrarcontrasena      matlab.ui.control.Button
        EditField_2            matlab.ui.control.EditField
        EditField_2Label       matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        Image2                 matlab.ui.control.Image
        LoginButton            matlab.ui.control.Button
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        dato % Description
        password
        cont
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Placeholder = 'Contraseña';
            app.password = '';
            app.cont = 0;
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            Borro = 'H';
            changingValue = event.Value;
            
            if length(changingValue) < length(app.dato)
                Borro = 'B';
                app.password(app.cont) = ''; %Borra dato
                app.cont=app.cont-1;
                app.dato = app.dato(1:end-1);
                app.EditField.Value = app.dato;
            end

            if length(changingValue) < 100 && Borro ~= 'B'
                app.password(app.cont+1) = changingValue(app.cont+1); %Guarda valor real
                app.cont=app.cont+1;
                app.dato = cat(2,app.dato,'*');
                app.EditField.Value = app.dato;
               
            end
            
            app.EditField_2.Value = app.password;

     
        end

        % Button pushed function: Mostrarcontrasena
        function MostrarcontrasenaPushed(app, event)
            app.EditField.Value = app.password;
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            if strcmp(app.password,'Informatica')
                Expediente_Clinico_2do
                pause(3)
                close(app.Login)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Login = uifigure('Visible', 'off');
            app.Login.Name = 'MATLAB App';
            app.Image = uiimage(app.Login);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fondo.jpg');
            app.LoginButton = uibutton(app.Login, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.Image2 = uiimage(app.Login);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'usuario.png');
            app.EditField = uieditfield(app.Login, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField_2Label = uilabel(app.Login);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.Login, 'text');
            app.Mostrarcontrasena = uibutton(app.Login, 'push');
            app.Mostrarcontrasena.ButtonPushedFcn = createCallbackFcn(app, @MostrarcontrasenaPushed, true);
            app.Mostrarcontrasena.Text = '';
            app.MostrarcontraseaLabel = uilabel(app.Login);
            app.MostrarcontraseaLabel.Text = 'Mostrar contraseña';
            app.Lamp = uilamp(app.Login);
            app.Label = uilabel(app.Login);
            % Show the figure after all components are created
            app.Login.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Log_in

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Login)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Login)
        end
    end
end