classdef camara < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        Panel2               matlab.ui.container.Panel
        GuardarImagenButton  matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        Panel                matlab.ui.container.Panel
        TomarfotoButton      matlab.ui.control.Button
        PreviewButton        matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        FechaHoy             matlab.ui.control.DatePicker
        FechaLabel           matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        DropDownLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            global a
            global v
            a = 1;

            v = videoinput("winvideo", 1, "MJPG_1280x720");
            v.ReturnedColorspace = "rgb";
            hImage = image(app.UIAxes,zeros(1280,720,3));
            app.UIAxes.XLim = [0 1280];
            app.UIAxes.YLim = [0 720];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            pbaspect(app.UIAxes,[1280,720,1]);
            preview(v,hImage)
            while (a == 1)
                pause(0.1);
            end
            
        end

        % Button pushed function: TomarfotoButton
        function TomarfotoButtonPushed(app, event)
            global v
            global a
            delete(v)
            v = videoinput("winvideo", 1, "MJPG_1280x720");
            v.ReturnedColorspace = "rgb";
            hImage2 = image(app.UIAxes2,zeros(1280,720,3));
            snapshot2 = getsnapshot(v);
            app.UIAxes2.XLim = [0 1280];
            app.UIAxes2.YLim = [0 720];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            a = 0;
            pbaspect(app.UIAxes2,[1280,720,1]);
            imshow(snapshot2,'Parent',app.UIAxes2);
            imwrite(snapshot2,'Picture.jpg');
        end

        % Button pushed function: GuardarImagenButton
        function GuardarImagenButtonPushed(app, event)
            F = app.FechaHoy.Value;
            Im = imread('Picture.jpg');
            Year = num2str(year(F));
            Month = num2str(month(F));
            Day = num2str(day(F));
            Format = '.jpg';
            pic_name =cat(2,Year,Month,Day,Format);
            imwrite(Im, pic_name)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'CDMX', 'EDO', 'Guadalajara', 'Mmario', 'Hola'};
            app.DropDown.Value = 'CDMX';
            app.FechaLabel = uilabel(app.UIFigure);
            app.FechaLabel.Text = 'Fecha';
            app.FechaHoy = uidatepicker(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PreviewButton = uibutton(app.Panel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.TomarfotoButton = uibutton(app.Panel, 'push');
            app.TomarfotoButton.ButtonPushedFcn = createCallbackFcn(app, @TomarfotoButtonPushed, true);
            app.TomarfotoButton.Text = 'Tomar foto';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes2 = uiaxes(app.Panel2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GuardarImagenButton = uibutton(app.Panel2, 'push');
            app.GuardarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarImagenButtonPushed, true);
            app.GuardarImagenButton.Text = 'Guardar Imagen';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IPN.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UPIBI.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camara

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end