classdef Masacorporal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HernndezMoncadaCristoferLabel   matlab.ui.control.Label
        GasparOrtizCarlosEmilioLabel    matlab.ui.control.Label
        GarciaEspinosaKibsaimLabel      matlab.ui.control.Label
        SalidaslPanel                   matlab.ui.container.Panel
        EvaluacionIMCEditField          matlab.ui.control.NumericEditField
        EvaluacionIMCEditFieldLabel     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        EntradasPanel                   matlab.ui.container.Panel
        MasacorporalEditField           matlab.ui.control.NumericEditField
        MasacorporalEditFieldLabel      matlab.ui.control.Label
        CalculoButton                   matlab.ui.control.Button
        PesokgEditField                 matlab.ui.control.NumericEditField
        PesokgEditFieldLabel            matlab.ui.control.Label
        AlturamEditField                matlab.ui.control.NumericEditField
        AlturamEditFieldLabel           matlab.ui.control.Label
        CalculadorademasacorporalLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ClculoButtonPushed(app, event)
            D=app.DistanciamEditField.Value;
            T=app.TiemposEditField.Value;
            V=D/T;
            app.VelocidadEditField.Value = V;
            
            if V>=0 && V<=10
                app.Lamp.Color = 'r';
            elseif V>10 && V<=20
                app.Lamp.Color = 'y';
            else 
                app.Lamp.Color = 'b';
            end
        end

        % Button pushed function: CalculoButton
        function CalculoButtonPushed(app, event)
            A=app.AlturamEditField.Value;
            P=app.PesokgEditField.Value;
            M=P/(A^2);
            app.MasacorporalEditField.Value=M;

            if M<=18.5
                app.Lamp.Color='r'
            elseif M>18.5 && M<= 24.9
                app.Lamp.Color='g'
            elseif M>24.9 && M<=29.9
                app.Lamp.Color='y'
            else M>30
                app.Lamp.Color='r'
            end
        end

        % Value changed function: EvaluacionIMCEditField
        function EvaluacionIMCEditFieldValueChanged(app, event)
            value = app.EvaluacionIMCEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculadorademasacorporalLabel = uilabel(app.UIFigure);
            app.CalculadorademasacorporalLabel.Text = 'Calculadora de masa corporal';
            app.EntradasPanel = uipanel(app.UIFigure);
            app.EntradasPanel.Title = 'Entradas';
            app.AlturamEditFieldLabel = uilabel(app.EntradasPanel);
            app.AlturamEditFieldLabel.Text = 'Altura (m)';
            app.AlturamEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.PesokgEditFieldLabel = uilabel(app.EntradasPanel);
            app.PesokgEditFieldLabel.Text = 'Peso (kg)';
            app.PesokgEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.CalculoButton = uibutton(app.EntradasPanel, 'push');
            app.CalculoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculoButtonPushed, true);
            app.CalculoButton.Text = 'Calculo';
            app.MasacorporalEditFieldLabel = uilabel(app.EntradasPanel);
            app.MasacorporalEditFieldLabel.Text = 'Masa corporal';
            app.MasacorporalEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.SalidaslPanel = uipanel(app.UIFigure);
            app.SalidaslPanel.Title = 'Salidasl';
            app.LampLabel = uilabel(app.SalidaslPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.SalidaslPanel);
            app.EvaluacionIMCEditFieldLabel = uilabel(app.SalidaslPanel);
            app.EvaluacionIMCEditFieldLabel.Text = 'Evaluacion IMC';
            app.EvaluacionIMCEditField = uieditfield(app.SalidaslPanel, 'numeric');
            app.EvaluacionIMCEditField.ValueChangedFcn = createCallbackFcn(app, @EvaluacionIMCEditFieldValueChanged, true);
            app.GarciaEspinosaKibsaimLabel = uilabel(app.UIFigure);
            app.GarciaEspinosaKibsaimLabel.Text = 'Garcia Espinosa Kibsaim';
            app.GasparOrtizCarlosEmilioLabel = uilabel(app.UIFigure);
            app.GasparOrtizCarlosEmilioLabel.Text = 'Gaspar Ortiz Carlos Emilio';
            app.HernndezMoncadaCristoferLabel = uilabel(app.UIFigure);
            app.HernndezMoncadaCristoferLabel.Text = 'Hernández Moncada Cristofer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Masacorporal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end